classdef TheGreatUniter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GreatUniterUIFigure     matlab.ui.Figure
        Slider                  matlab.ui.control.Slider
        InputWindowLabel        matlab.ui.control.Label
        SendButton              matlab.ui.control.Button
        InputTextArea           matlab.ui.control.TextArea
        testButton              matlab.ui.control.StateButton
        ProgressPanel           matlab.ui.container.Panel
        UITable                 matlab.ui.control.Table
        UIAxesLog               matlab.ui.control.UIAxes
        Panel_2                 matlab.ui.container.Panel
        LabelDevice             matlab.ui.control.Label
        addButton               matlab.ui.control.Button
        DevicesTree             matlab.ui.container.Tree
        OPMRUBINNode            matlab.ui.container.TreeNode
        OPMTHORLABSNode         matlab.ui.container.TreeNode
        OSAYOKOGAWANode         matlab.ui.container.TreeNode
        FPGANode                matlab.ui.container.TreeNode
        ATTENUATORDICONNode     matlab.ui.container.TreeNode
        SWITCHOSAEDFANode       matlab.ui.container.TreeNode
        SENSORNode              matlab.ui.container.TreeNode
        OLSRUBINNode            matlab.ui.container.TreeNode
        TheGreatUniterLabel     matlab.ui.control.Label
        Panel_4                 matlab.ui.container.Panel
        savelogButton           matlab.ui.control.Button
        PercentLabel            matlab.ui.control.Label
        ProgressLabel           matlab.ui.control.Label
        WARNINGLaserinuseLabel  matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        clearButton             matlab.ui.control.Button
        OutputWindowLabel       matlab.ui.control.Label
        OutputTextArea          matlab.ui.control.TextArea
        Panel_3                 matlab.ui.container.Panel
        LabelTest               matlab.ui.control.Label
        RunButton               matlab.ui.control.Button
        Tree                    matlab.ui.container.Tree
        FPGAtestsNode           matlab.ui.container.TreeNode
        TestphotodiodeNode      matlab.ui.container.TreeNode
        TestattenuatorNode      matlab.ui.container.TreeNode
        TesttransceiverNode     matlab.ui.container.TreeNode
        TestotherparameterNode  matlab.ui.container.TreeNode
        TestloaddriverNode      matlab.ui.container.TreeNode
        TestlaserdiodeNode      matlab.ui.container.TreeNode
        EDFAtestsNode           matlab.ui.container.TreeNode
        AutotestEDFANode        matlab.ui.container.TreeNode
        CalibrateEDFANode       matlab.ui.container.TreeNode
        TestVDminmeanmaxNode    matlab.ui.container.TreeNode
        TestcoeffNode           matlab.ui.container.TreeNode
        PanelFigures            matlab.ui.container.Panel
        PlotinnewwindowButton   matlab.ui.control.Button
        YDropDown               matlab.ui.control.DropDown
        YDropDownLabel          matlab.ui.control.Label
        XDropDown               matlab.ui.control.DropDown
        XDropDownLabel          matlab.ui.control.Label
        UIAxesProgress2         matlab.ui.control.UIAxes
        UIAxesProgress          matlab.ui.control.UIAxes
        Panel                   matlab.ui.container.Panel
        TextArea_2              matlab.ui.control.TextArea
        Label_2                 matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        Label                   matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
    end

    properties
        folder
        userText
        x
        y
    end
    properties %devices
        calledAppOPMrubin
        calledAppOPMthorlabs
        calledAppOSA
        calledAppFPGA
        calledAppATTENdicon
        calledAppSENSORS
        calledAppSWITCH
    end
    properties %functions
        calledAppTestAttenuator
        calledAppTestTransceiver
        calledAppTestPhotodiode
        calledAppTestLaserDiode
        calledAppTestLoadDriver
        calledAppTestOtherParameter
        calledAppAutoTestEDFA
        calledAppAutoCalibEDFA
        calledTestVD_minMeanMax
        calledAppTestCoeff
    end
    methods (Access = public)
        % Очищение окна вывода
        function clearOutput(app)
            app.OutputTextArea.Value = '';
        end
        % Добавить строку в окно вывода
        function addStrOutput(app,str)
            oldOutput = app.OutputTextArea.Value;
            newOutput = [datestr(now, 'HH:MM:SS'),': ',str];
            app.OutputTextArea.Value = [oldOutput; newOutput];
        end
        function clearMainTable(app)
            app.UITable.Data = [];
        end
        function appendMainTable(app,newRow)
            app.UITable.Data = [app.UITable.Data; newRow];
        end
        function setLabelsProgressAxes(app,title,xLabel,yLabel)
            %title(app.UIAxesProgress,title);
            xlabel(app.UIAxesProgress,xLabel);
            ylabel(app.UIAxesProgress,yLabel);

        end
        function addPointProgressAxes(app,x,y,color)
            hold(app.UIAxesProgress,'on')
            plot(app.UIAxesProgress,x,y,'.','MarkerSize',10,'Color',color);
        end
        function clearProgressAxes(app)
            cla(app.UIAxesProgress);
        end
        function setLabelsProgressAxes2(app,title,xLabel,yLabel)
            %title(app.UIAxesProgress2,title);
            xlabel(app.UIAxesProgress2,xLabel);
            ylabel(app.UIAxesProgress2,yLabel);
        end
        function addPointProgressAxes2(app,x,y,color)
            hold(app.UIAxesProgress2,'on')
            plot(app.UIAxesProgress2,x,y,'.','MarkerSize',10,'Color',color);
        end
        function clearProgressAxes2(app)
            cla(app.UIAxesProgress2);
        end


        %         function setLegendProgressAxes(app,legend)
        %             legend(app.UIAxesProgress,legend);
        %         end

        function enableWarningLaser(app)
            app.WARNINGLaserinuseLabel.FontColor = [1, 1, 1];
            app.WARNINGLaserinuseLabel.BackgroundColor = [1, 0, 0];
            app.Lamp.Color = [1, 0, 0];
        end
        function disableWarningLaser(app)
            app.WARNINGLaserinuseLabel.FontColor = [0.80, 0.80, 0.80];
            app.WARNINGLaserinuseLabel.BackgroundColor = 'none';
            app.Lamp.Color = [0.94,0.94,0.94];
        end
        function refreshProgress(app,newProgress)
            app.ProgressLabel.Text = num2str(newProgress);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;

            app.folder = app.TextArea.Value{1};
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            output = uigetdir('C:\EDFA\AllTestsEDFA','Select folder');
            if ~isequal(output,0)
                output = [output '\'];
                app.folder = output;
                app.TextArea.Value = output;
            end
            figure(app.GreatUniterUIFigure);
            app.Panel_2.Enable = 'on';
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            value = app.DevicesTree.SelectedNodes;
            if value == app.OPMRUBINNode
                app.calledAppOPMrubin = appOPMrubin(app);
            elseif value == app.OPMTHORLABSNode
                app.calledAppOPMthorlabs = appOPMthorlabs(app);
            elseif value == app.OSAYOKOGAWANode
                app.calledAppOSA = appOSA(app);
            elseif value == app.FPGANode
                app.calledAppFPGA = appFPGA(app);
            elseif value == app.ATTENUATORDICONNode
                app.calledAppATTENdicon = appATTENdicon(app);
            elseif value == app.SENSORNode
                app.calledAppSENSORS = appSENSOR(app);
            elseif value == app.SWITCHOSAEDFANode
                app.calledAppSWITCH = appSWITCH(app);
            end
            app.Panel_3.Enable = 'on';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %             if isequal(app.TextArea.Value{1},'не выбрана')
            %                 error('Выберите папку и перезапустите проверку')
            %             end
            value = app.Tree.SelectedNodes;
            if value == app.TestattenuatorNode
                app.calledAppTestAttenuator = appTestAttenuator(app);
            elseif value == app.TesttransceiverNode
                app.calledAppTestTransceiver = appTestTransceiver(app);
            elseif value == app.TestphotodiodeNode
                app.calledAppTestPhotodiode = appTestPhotodiode(app);
            elseif value == app.TestlaserdiodeNode
                app.calledAppTestLaserDiode = appTestLaserDiode(app);
            elseif value == app.TestloaddriverNode
                app.calledAppTestLoadDriver = appTestLoadDriver(app);
            elseif value == app.TestotherparameterNode
                app.calledAppTestOtherParameter = appTestOtherParameter(app);

            elseif value == app.AutotestEDFANode
                app.calledAppAutoTestEDFA = appAutoTestEDFA(app);
            elseif value == app.CalibrateEDFANode
                app.calledAppAutoCalibEDFA = appAutoCalibEDFA(app);

            elseif value == app.TestVDminmeanmaxNode
                app.calledTestVD_minMeanMax = TestVD_minMeanMax(app);
                
            elseif value == app.TestcoeffNode
app.calledAppTestCoeff = appTestCoeff(app);
            end
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            clc;
            clearOutput(app);
            clearMainTable(app);
            clearProgressAxes(app);
            clearProgressAxes2(app);
            cla(app.UIAxesLog);
        end

        % Value changed function: testButton
        function testButtonValueChanged(app, event)
            value = app.testButton.Value;
            if value
                enableWarningLaser(app);
                newProgress = 50;
                refreshProgress(app,newProgress);
str = 'test1';
                addStrOutput(app,str);
            else
                disableWarningLaser(app);
                newProgress = 100;
                refreshProgress(app,newProgress);
str = 'test2';
addStrOutput(app,str);
            end
        end

        % Button pushed function: savelogButton
        function savelogButtonPushed(app, event)
            textForSave = app.OutputTextArea.Value
            save('textForSave.mat','textForSave')
%             strForSave = [app.folder createFilename('log',now) '.txt'];
%             fid=fopen(strForSave,'w');
%             fprintf(fid, textForSave);
%             fclose(fid);
% 
%             for k=1:length(fileName)
%     List=textread(fileName{1,k},'%s','delimiter','\n');
%     %arrange them in order of k if you want in a cell array
%     FinalList{k,1}=List;
%     %or print them into a file.
%     fprintf(fid, [cell2mat(List)  '\n']);
% end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GreatUniterUIFigure = uifigure('Visible', 'off');
            app.GreatUniterUIFigure.Name = 'Measure Center App';
            app.GreatUniterUIFigure.Icon = 'icons8-share-512.png';
            app.Panel = uipanel(app.GreatUniterUIFigure);
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'icons8-folder-512.png';
            app.SaveButton.Tooltip = {'select folder'};
            app.SaveButton.Text = '';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Выберите папку для сохранения файлов';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.Editable = 'off';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = 'Введите текст, который будет добавляться к названиям';
            app.TextArea_2 = uitextarea(app.Panel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.PanelFigures = uipanel(app.GreatUniterUIFigure);
            app.UIAxesProgress = uiaxes(app.PanelFigures);
            title(app.UIAxesProgress, 'Title')
            xlabel(app.UIAxesProgress, 'X')
            ylabel(app.UIAxesProgress, 'Y')
            zlabel(app.UIAxesProgress, 'Z')
            app.UIAxesProgress2 = uiaxes(app.PanelFigures);
            title(app.UIAxesProgress2, 'Title')
            xlabel(app.UIAxesProgress2, 'X')
            ylabel(app.UIAxesProgress2, 'Y')
            zlabel(app.UIAxesProgress2, 'Z')
            app.XDropDownLabel = uilabel(app.PanelFigures);
            app.XDropDownLabel.Enable = 'off';
            app.XDropDownLabel.Text = 'X ';
            app.XDropDown = uidropdown(app.PanelFigures);
            app.XDropDown.Enable = 'off';
            app.YDropDownLabel = uilabel(app.PanelFigures);
            app.YDropDownLabel.Enable = 'off';
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.PanelFigures);
            app.YDropDown.Enable = 'off';
            app.PlotinnewwindowButton = uibutton(app.PanelFigures, 'push');
            app.PlotinnewwindowButton.Enable = 'off';
            app.PlotinnewwindowButton.Text = 'Plot in new window';
            app.Panel_3 = uipanel(app.GreatUniterUIFigure);
            app.Panel_3.Enable = 'off';
            app.Tree = uitree(app.Panel_3);
            app.FPGAtestsNode = uitreenode(app.Tree);
            app.FPGAtestsNode.Text = 'FPGA tests';
            app.TestphotodiodeNode = uitreenode(app.FPGAtestsNode);
            app.TestphotodiodeNode.Text = 'Test photodiode';
            app.TestattenuatorNode = uitreenode(app.FPGAtestsNode);
            app.TestattenuatorNode.Text = 'Test attenuator';
            app.TesttransceiverNode = uitreenode(app.FPGAtestsNode);
            app.TesttransceiverNode.Text = 'Test transceiver';
            app.TestotherparameterNode = uitreenode(app.FPGAtestsNode);
            app.TestotherparameterNode.Text = 'Test other parameter';
            app.TestloaddriverNode = uitreenode(app.FPGAtestsNode);
            app.TestloaddriverNode.Text = 'Test load driver';
            app.TestlaserdiodeNode = uitreenode(app.FPGAtestsNode);
            app.TestlaserdiodeNode.Text = 'Test laser diode';
            app.EDFAtestsNode = uitreenode(app.Tree);
            app.EDFAtestsNode.Text = 'EDFA tests';
            app.AutotestEDFANode = uitreenode(app.EDFAtestsNode);
            app.AutotestEDFANode.Text = 'Auto test EDFA';
            app.CalibrateEDFANode = uitreenode(app.EDFAtestsNode);
            app.CalibrateEDFANode.Text = 'Calibrate EDFA';
            app.TestVDminmeanmaxNode = uitreenode(app.Tree);
            app.TestVDminmeanmaxNode.Text = 'Test VD (min,mean,max)';
            app.TestcoeffNode = uitreenode(app.Tree);
            app.TestcoeffNode.Text = 'Test coeff';
            app.RunButton = uibutton(app.Panel_3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'icons8-add-property-96.png';
            app.RunButton.Tooltip = {'select test'};
            app.RunButton.Text = '';
            app.LabelTest = uilabel(app.Panel_3);
            app.LabelTest.Text = 'Выберите функцию и нажмите "+"';
            app.Panel_4 = uipanel(app.GreatUniterUIFigure);
            app.OutputTextArea = uitextarea(app.Panel_4);
            app.OutputTextArea.Editable = 'off';
            app.OutputWindowLabel = uilabel(app.Panel_4);
            app.OutputWindowLabel.Text = 'Output Window';
            app.clearButton = uibutton(app.Panel_4, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.Lamp = uilamp(app.Panel_4);
            app.WARNINGLaserinuseLabel = uilabel(app.Panel_4);
            app.WARNINGLaserinuseLabel.Text = {'WARNING'; 'Laser in use'};
            app.ProgressLabel = uilabel(app.Panel_4);
            app.ProgressLabel.Text = '0';
            app.PercentLabel = uilabel(app.Panel_4);
            app.PercentLabel.Text = '%';
            app.savelogButton = uibutton(app.Panel_4, 'push');
            app.savelogButton.ButtonPushedFcn = createCallbackFcn(app, @savelogButtonPushed, true);
            app.savelogButton.Text = 'save log';
            app.TheGreatUniterLabel = uilabel(app.GreatUniterUIFigure);
            app.TheGreatUniterLabel.Text = 'The Great Uniter';
            app.Panel_2 = uipanel(app.GreatUniterUIFigure);
            app.Panel_2.Enable = 'off';
            app.DevicesTree = uitree(app.Panel_2);
            app.OPMRUBINNode = uitreenode(app.DevicesTree);
            app.OPMRUBINNode.Text = 'OPM | RUBIN';
            app.OPMTHORLABSNode = uitreenode(app.DevicesTree);
            app.OPMTHORLABSNode.Text = 'OPM | THORLABS';
            app.OSAYOKOGAWANode = uitreenode(app.DevicesTree);
            app.OSAYOKOGAWANode.Text = 'OSA | YOKOGAWA';
            app.FPGANode = uitreenode(app.DevicesTree);
            app.FPGANode.Text = 'FPGA';
            app.ATTENUATORDICONNode = uitreenode(app.DevicesTree);
            app.ATTENUATORDICONNode.Text = 'ATTENUATOR | DICON';
            app.SWITCHOSAEDFANode = uitreenode(app.DevicesTree);
            app.SWITCHOSAEDFANode.Text = 'SWITCH | OSA-EDFA';
            app.SENSORNode = uitreenode(app.DevicesTree);
            app.SENSORNode.Text = 'SENSOR';
            app.OLSRUBINNode = uitreenode(app.DevicesTree);
            app.OLSRUBINNode.Text = 'OLS | RUBIN';
            app.addButton = uibutton(app.Panel_2, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Icon = 'icons8-plus-math-96.png';
            app.addButton.Tooltip = {'select device'};
            app.addButton.Text = '';
            app.LabelDevice = uilabel(app.Panel_2);
            app.LabelDevice.Text = 'Выберите устройство и нажмите "+"';
            app.ProgressPanel = uipanel(app.GreatUniterUIFigure);
            app.UIAxesLog = uiaxes(app.ProgressPanel);
            title(app.UIAxesLog, 'Spectrum')
            xlabel(app.UIAxesLog, 'X')
            ylabel(app.UIAxesLog, 'Y')
            zlabel(app.UIAxesLog, 'Z')
            app.UITable = uitable(app.ProgressPanel);
            app.UITable.ColumnName = {'Name'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.testButton = uibutton(app.GreatUniterUIFigure, 'state');
            app.testButton.ValueChangedFcn = createCallbackFcn(app, @testButtonValueChanged, true);
            app.testButton.Text = 'test';
            app.InputTextArea = uitextarea(app.GreatUniterUIFigure);
            app.InputTextArea.Enable = 'off';
            app.SendButton = uibutton(app.GreatUniterUIFigure, 'push');
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.InputWindowLabel = uilabel(app.GreatUniterUIFigure);
            app.InputWindowLabel.Enable = 'off';
            app.InputWindowLabel.Text = 'Input Window';
            app.Slider = uislider(app.GreatUniterUIFigure);
            app.Slider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Slider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.Slider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
            % Show the figure after all components are created
            app.GreatUniterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TheGreatUniter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GreatUniterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GreatUniterUIFigure)
        end
    end
end