classdef appAddDevice_ATTENniMyDaq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_2                       matlab.ui.container.Panel
        Tip1Image                     matlab.ui.control.Image
        plotButton                    matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        AddattenuatortableVoltagevsAttenuationTextArea  matlab.ui.control.TextArea
        AddattenuatortableVoltagevsAttenuationLabel  matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        Panel                         matlab.ui.container.Panel
        Tip2Image                     matlab.ui.control.Image
        dBmLabel                      matlab.ui.control.Label
        okButton                      matlab.ui.control.Button
        Attenuationao0EditField       matlab.ui.control.NumericEditField
        Attenuationao0EditFieldLabel  matlab.ui.control.Label
        AttenuatornimyDAQLabel        matlab.ui.control.Label
    end

    properties (Access = public)
        callingApp
        folder
        %userText
    end
    properties (Access = public)
        tableAttenuatorDiconPassport
        attenuatorDicon
    end
    methods (Access = public)
        function output = connect(app)
%             try
                app.attenuatorDicon = [];
                app.attenuatorDicon = AttenuatorDicon(app);
                output = 'DICON connected';
                app.Panel.Enable = 'on';
%             catch ME
%                 fig = uifigure;
%                 output = "Connection failed";
%                 uialert(fig,ME.message,output);
%                 app.Switch.Value = "Disconnect";
%             end
        end
        function output = disconnect(app)
            if ~isequal(class(app.attenuatorDicon),'double')
                app.attenuatorDicon = deleteVirtualObject(app.attenuatorDicon);
                %заменить на реальную проверку
                app.Panel.Enable = 'off';
            end
            output = 'DICON disconnected';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            
            app.folder = MainApp.folder;
            %app.userText = MainApp.userText;
            
            app.tableAttenuatorDiconPassport = app.AddattenuatortableVoltagevsAttenuationTextArea.Value{1};
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select DICON passport table','AttenuatorDiconPassportCut.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableAttenuatorDiconPassport = output;
            app.AddattenuatortableVoltagevsAttenuationTextArea.Value = output;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Connect"
                output = connect(app);
            elseif value == "Disconnect"
                output = disconnect(app);
            end
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            attenuation = app.Attenuationao0EditField.Value;
            app.attenuatorDicon = setAttenuation(app.attenuatorDicon,attenuation);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AttenuatornimyDAQLabel = uilabel(app.UIFigure);
            app.AttenuatornimyDAQLabel.Text = 'Attenuator (ni myDAQ)';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Enable = 'off';
            app.Attenuationao0EditFieldLabel = uilabel(app.Panel);
            app.Attenuationao0EditFieldLabel.Text = 'Attenuation (ao0)';
            app.Attenuationao0EditField = uieditfield(app.Panel, 'numeric');
            app.okButton = uibutton(app.Panel, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            app.dBmLabel = uilabel(app.Panel);
            app.dBmLabel.Text = 'dBm';
            app.Tip2Image = uiimage(app.Panel);
            app.Tip2Image.Tooltip = {'Attenuation should be in range [minimum;maximum] from table.'; 'Here we use Analog Output channel 0.'};
            app.Tip2Image.ImageSource = 'gu_help.png';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Switch = uiswitch(app.Panel_2, 'slider');
            app.Switch.Items = {'Disconnect', 'Connect'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Disconnect';
            app.AddattenuatortableVoltagevsAttenuationLabel = uilabel(app.Panel_2);
            app.AddattenuatortableVoltagevsAttenuationLabel.Text = {'Add attenuator table'; '(Voltage vs. Attenuation)'};
            app.AddattenuatortableVoltagevsAttenuationTextArea = uitextarea(app.Panel_2);
            app.AddattenuatortableVoltagevsAttenuationTextArea.Value = {'AttenuatorDiconPassportCut.xlsx'};
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'gu_microsoft-excel.png';
            app.Button.Text = '';
            app.plotButton = uibutton(app.Panel_2, 'push');
            app.plotButton.Text = 'plot';
            app.Tip1Image = uiimage(app.Panel_2);
            app.Tip1Image.Tooltip = {'Table should have 2 columns: attenuation_dBm, voltage_V in this order.'; 'Note that values should form a function V = f(dBm) for better approximation. You can test yourself by looking at the plot below.'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_ATTENniMyDaq(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end