classdef appAddDevice_FPGA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FPGAUIFigure              matlab.ui.Figure
        ConnectSwitch             matlab.ui.control.Switch
        AdminFunctionPanel        matlab.ui.container.Panel
        WriteEEPROMButton         matlab.ui.control.Button
        WriteEepromEditField      matlab.ui.control.EditField
        ConnectPanel              matlab.ui.container.Panel
        downloadtxtButton         matlab.ui.control.Button
        MainTablePathTextArea     matlab.ui.control.TextArea
        SelectaddresstableLabel   matlab.ui.control.Label
        InterfaceDropDown         matlab.ui.control.DropDown
        InterfaceDropDownLabel    matlab.ui.control.Label
        FunctionPanel             matlab.ui.container.Panel
        DataformatEditField       matlab.ui.control.EditField
        DataformatEditFieldLabel  matlab.ui.control.Label
        UnlockWritetoEEPROMfunctionCheckBox  matlab.ui.control.CheckBox
        AddressnameListBox        matlab.ui.control.ListBox
        AddressnameListBoxLabel   matlab.ui.control.Label
        writeButton               matlab.ui.control.Button
        readButton                matlab.ui.control.Button
        WriteValueEditField       matlab.ui.control.EditField
        ReadValueEditField        matlab.ui.control.EditField
        FPGALabel                 matlab.ui.control.Label
    end


    properties (Access = public)
        callingApp
        folder
    end

    properties (Access = public)
        mainTable
        mainObj
        password
    end

    methods (Access = public)
        function connect(app)
            try
                app.mainObj = [];
                appStruct = struct('InterfaceDropDown',app.InterfaceDropDown.Value,...
                    'addressTablePath',app.MainTablePathTextArea.Value{1});
                app.mainObj = Device_FPGA(appStruct);
                readFile(app);
                output = "FPGA connected";
                addStrOutput(app.callingApp,output);
                app.ConnectPanel.Enable = 'off';
                app.FunctionPanel.Enable = 'on';
                value = app.AddressnameListBox.Value;
                app.DataformatEditField.Value = whatFRM(app.mainObj,value);
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
                output = "Wrong connection";
                addStrOutput(app.callingApp,output);
                app.ConnectSwitch.Value = "Disconnect";
                app.ConnectPanel.Enable = 'on';
                app.FunctionPanel.Enable = 'off';
            end
        end
        function disconnect(app)
            if ~isequal(class(app.mainObj),'double')
                app.mainObj = deleteVirtualObject(app.mainObj);
                output = 'FPGA disconnected';
                addStrOutputWithCheck(app.callingApp,output);
            end
            app.ConnectPanel.Enable = 'on';
            app.FunctionPanel.Enable = 'off';
            app.UnlockWritetoEEPROMfunctionCheckBox.Value = 0;
            app.AdminFunctionPanel.Enable = "off";
        end
        function selectFile(app)
            [file,path] = uigetfile('*.txt','Select ADDRESS.txt file',app.MainTablePathTextArea.Value{1});
            output = [path file];
            if isequal(file,0) || ~isfile(output)
                output = '';
            end
            app.MainTablePathTextArea.Value = output;
        end
        function readFile(app)
            if isempty(app.mainObj.addressTable)
                app.ConnectSwitch.Enable = "off";
            else
                app.ConnectSwitch.Enable = "on";
                app.AddressnameListBox.Items = app.mainObj.addressTable.Properties.RowNames;
            end
        end
        function readDataFPGA(app)
            try
                name = app.AddressnameListBox.Value;
                app.mainObj = readData(app.mainObj,"FLASH",name);
                value = app.mainObj.lastRead;
                app.ReadValueEditField.Value = value;
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
                addStrOutput(app.callingApp,"Reading failed");
            end
        end
        function writeAndReadDataFPGA(app,mem)
            try
                name = app.AddressnameListBox.Value;
                wvalue = app.WriteValueEditField.Value;
                app.mainObj = writeAndReadData(app.mainObj,mem,name,wvalue);
                value = app.mainObj.lastRead;
                app.ReadValueEditField.Value = value;
            catch ME
                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message');%,'Interpreter','html');
                addStrOutput(app.callingApp,"Writing failed");
            end
        end
        function loadFromMemFileToApp(app)
            load('TheGreatUniterMemory.mat','appAddDevice_FPGA');
            app.MainTablePathTextArea.Value = appAddDevice_FPGA.MainTablePathTextArea;
            app.password = appAddDevice_FPGA.Password;
        end
        function saveFromAppToMemFile(app)
            appAddDevice_FPGA.MainTablePathTextArea = app.MainTablePathTextArea.Value{1};
            appAddDevice_FPGA.Password = app.password;
            save('TheGreatUniterMemory.mat','appAddDevice_FPGA','-append');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            loadFromMemFileToApp(app);
        end

        % Button pushed function: downloadtxtButton
        function downloadtxtButtonPushed(app, event)
            selectFile(app);
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            if value == "Connect"
                connect(app);
            elseif value == "Disconnect"
                disconnect(app);
            end
        end

        % Button pushed function: readButton
        function readButtonPushed(app, event)
            readDataFPGA(app);
        end

        % Button pushed function: writeButton
        function writeButtonPushed(app, event)
            writeAndReadDataFPGA(app,"FLASH");
        end

        % Button pushed function: WriteEEPROMButton
        function WriteEEPROMButtonPushed(app, event)
            writeAndReadDataFPGA(app,"EEPROM");
        end

        % Value changed function: UnlockWritetoEEPROMfunctionCheckBox
        function UnlockWritetoEEPROMfunctionCheckBoxValueChanged(app, event)
            value = app.UnlockWritetoEEPROMfunctionCheckBox.Value;
            if value
                prompt = {'Enter admin password'};
                dlgtitle = 'Password';
                dims = [1 35];
                inputPassword = inputdlg(prompt,dlgtitle,dims);
                if ~isempty(inputPassword) && isequal(inputPassword,{app.password})
                    app.AdminFunctionPanel.Enable = 'on';
                    addStrOutput(app.callingApp,'Unlocked Write to EEPROM');
                else
                    app.UnlockWritetoEEPROMfunctionCheckBox.Value = false;
                    addStrOutput(app.callingApp,'Wrong or empty password');
                end
            else
                app.AdminFunctionPanel.Enable = 'off';
                addStrOutput(app.callingApp,'Locked Write to EEPROM');
            end
        end

        % Value changed function: AddressnameListBox
        function AddressnameListBoxValueChanged(app, event)
            value = app.AddressnameListBox.Value;
            app.DataformatEditField.Value = whatFRM(app.mainObj,value);
        end

        % Close request function: FPGAUIFigure
        function FPGAUIFigureCloseRequest(app, event)
            fig = app.FPGAUIFigure;
            closeRequestWarning(app,fig);
        end

        % Value changed function: WriteValueEditField
        function WriteValueEditFieldValueChanged(app, event)
            value = app.WriteValueEditField.Value;
            if contains(value,',')
                app.WriteValueEditField.BackgroundColor = app.callingApp.ERROR_COLOR;
                app.writeButton.Enable = 'off';
            else
                app.WriteValueEditField.BackgroundColor = app.callingApp.DEFAULT_COLOR;
                app.writeButton.Enable = 'on';
            end
        end

        % Value changed function: WriteEepromEditField
        function WriteEepromEditFieldValueChanged(app, event)
            value = app.WriteEepromEditField.Value;
            if contains(value,',')
                app.WriteEepromEditField.BackgroundColor = app.callingApp.ERROR_COLOR;
                app.WriteEEPROMButton.Enable = 'off';
            else
                app.WriteEepromEditField.BackgroundColor = app.callingApp.DEFAULT_COLOR;
                app.WriteEEPROMButton.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FPGAUIFigure = uifigure('Visible', 'off');
            app.FPGAUIFigure.Name = 'FPGA';
            app.FPGAUIFigure.Icon = 'gu_device.png';
            app.FPGAUIFigure.CloseRequestFcn = createCallbackFcn(app, @FPGAUIFigureCloseRequest, true);
            app.FPGALabel = uilabel(app.FPGAUIFigure);
            app.FPGALabel.Text = 'FPGA';
            app.FunctionPanel = uipanel(app.FPGAUIFigure);
            app.FunctionPanel.Enable = 'off';
            app.ReadValueEditField = uieditfield(app.FunctionPanel, 'text');
            app.ReadValueEditField.Editable = 'off';
            app.WriteValueEditField = uieditfield(app.FunctionPanel, 'text');
            app.WriteValueEditField.ValueChangedFcn = createCallbackFcn(app, @WriteValueEditFieldValueChanged, true);
            app.readButton = uibutton(app.FunctionPanel, 'push');
            app.readButton.ButtonPushedFcn = createCallbackFcn(app, @readButtonPushed, true);
            app.readButton.Text = 'read';
            app.writeButton = uibutton(app.FunctionPanel, 'push');
            app.writeButton.ButtonPushedFcn = createCallbackFcn(app, @writeButtonPushed, true);
            app.writeButton.Text = 'write';
            app.AddressnameListBoxLabel = uilabel(app.FunctionPanel);
            app.AddressnameListBoxLabel.Text = 'Address name';
            app.AddressnameListBox = uilistbox(app.FunctionPanel);
            app.AddressnameListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 1', 'Item 2', 'Item 3', 'Item 4'};
            app.AddressnameListBox.ValueChangedFcn = createCallbackFcn(app, @AddressnameListBoxValueChanged, true);
            app.UnlockWritetoEEPROMfunctionCheckBox = uicheckbox(app.FunctionPanel);
            app.UnlockWritetoEEPROMfunctionCheckBox.ValueChangedFcn = createCallbackFcn(app, @UnlockWritetoEEPROMfunctionCheckBoxValueChanged, true);
            app.UnlockWritetoEEPROMfunctionCheckBox.Text = 'Unlock Write to EEPROM function';
            app.DataformatEditFieldLabel = uilabel(app.FunctionPanel);
            app.DataformatEditFieldLabel.Text = 'Data format';
            app.DataformatEditField = uieditfield(app.FunctionPanel, 'text');
            app.DataformatEditField.Editable = 'off';
            app.DataformatEditField.Tooltip = {'Data format'};
            app.ConnectPanel = uipanel(app.FPGAUIFigure);
            app.InterfaceDropDownLabel = uilabel(app.ConnectPanel);
            app.InterfaceDropDownLabel.Text = 'Interface';
            app.InterfaceDropDown = uidropdown(app.ConnectPanel);
            app.InterfaceDropDown.Items = {'JTAG'};
            app.InterfaceDropDown.Value = 'JTAG';
            app.SelectaddresstableLabel = uilabel(app.ConnectPanel);
            app.SelectaddresstableLabel.Text = 'Select address table *.txt:';
            app.MainTablePathTextArea = uitextarea(app.ConnectPanel);
            app.MainTablePathTextArea.Value = {'ADDRESS.txt'};
            app.downloadtxtButton = uibutton(app.ConnectPanel, 'push');
            app.downloadtxtButton.ButtonPushedFcn = createCallbackFcn(app, @downloadtxtButtonPushed, true);
            app.downloadtxtButton.Icon = 'gu_download_txt.png';
            app.downloadtxtButton.Tooltip = {'Select txt address table'};
            app.downloadtxtButton.Text = '';
            app.AdminFunctionPanel = uipanel(app.FPGAUIFigure);
            app.AdminFunctionPanel.Enable = 'off';
            app.WriteEepromEditField = uieditfield(app.AdminFunctionPanel, 'text');
            app.WriteEepromEditField.ValueChangedFcn = createCallbackFcn(app, @WriteEepromEditFieldValueChanged, true);
            app.WriteEEPROMButton = uibutton(app.AdminFunctionPanel, 'push');
            app.WriteEEPROMButton.ButtonPushedFcn = createCallbackFcn(app, @WriteEEPROMButtonPushed, true);
            app.WriteEEPROMButton.Text = 'Write EEPROM';
            app.ConnectSwitch = uiswitch(app.FPGAUIFigure, 'slider');
            app.ConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.ConnectSwitch.Value = 'Disconnect';
            % Show the figure after all components are created
            app.FPGAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_FPGA(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FPGAUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.FPGAUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FPGAUIFigure)
        end
    end
end