classdef appAddDevice_OPMrubin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OPMRubinUIFigure               matlab.ui.Figure
        EditField                      matlab.ui.control.EditField
        DisplaywavelengthButton        matlab.ui.control.Button
        OPMcomPortsEditField           matlab.ui.control.EditField
        COMportsEditFieldLabel         matlab.ui.control.Label
        okButton                       matlab.ui.control.Button
        ConnectPanel                   matlab.ui.container.Panel
        OPMnameEditField               matlab.ui.control.EditField
        OPMnameEditFieldLabel          matlab.ui.control.Label
        SerialportDropDown             matlab.ui.control.DropDown
        SerialportDropDownLabel        matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        ConnectSwitch                  matlab.ui.control.Switch
        FunctionPanel                  matlab.ui.container.Panel
        nmLabel                        matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        SaveButton                     matlab.ui.control.Button
        dBmLabel                       matlab.ui.control.Label
        PowerWEditField                matlab.ui.control.NumericEditField
        PowerEditField                 matlab.ui.control.NumericEditField
        ReadpowerButton                matlab.ui.control.Button
        ChoosewavelengthDropDown       matlab.ui.control.DropDown
        ChoosewavelengthDropDownLabel  matlab.ui.control.Label
        OPMRubinLabel                  matlab.ui.control.Label
    end

    properties (Access = private)
        callingApp
        folder
        userText
    end
    properties (Access = public)
        opmArr
    end
    methods (Access = public)
        function refreshSerialportDropDown(app)
            app.SerialportDropDown.Items = serialportlist("available");
        end
        function output = connect(app)
            try
                app.opmArr = [];
                app.opmArr = OpticalPowerMeterArr(app);
                output = getInfoOpenComArr(app.opmArr);
                app.FunctionPanel.Enable = 'on';
            catch ME
                
                app.ConnectSwitch.Value = "Disconnect";

                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message','Interpreter','html');
                output = "Wrong connection";
                %app.ConnectSwitch.Value = "Disconnect";


            end
        end
        function output = disconnect(app)
            if ~isequal(class(app.opmArr),'double')
                app.opmArr = deleteVirtualObjectArr(app.opmArr);
                output = getInfoCloseComArr(app.opmArr);
                app.FunctionPanel.Enable = 'off';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
%             app.callingApp = MainApp;
%             refreshSerialportDropDown(app);
%             app.folder = MainApp.folder;
%             app.userText = MainApp.userText;
            
            %название файла для сохранения данных ?
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            if value == "Connect"
                output = connect(app);
            elseif value == "Disconnect"
                output = disconnect(app);
            end
            addStrOutput(app.callingApp,output);
        end

        % Callback function
        function RunButtonPushed(app, event)
            value = app.Tree.SelectedNodes;
            if value == app.ReadpowerRubinNode
                
            elseif value == app.DispwavelengthRubinNode
                
            elseif value == app.SavepowertableNode
                
            end
            
        end

        % Button pushed function: ReadpowerButton
        function ReadpowerButtonPushed(app, event)
            app.opmArr = readPowerdBmArr(app.opmArr);
                output = getInfoPowerArr(app.opmArr);
                addStrOutput(app.callingApp,output);
        end

        % Button pushed function: DisplaywavelengthButton
        function DisplaywavelengthButtonPushed(app, event)
            output = getInfoWavelengthArr(app.opmArr);
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            output = "function is not ready";
            addStrOutput(app.callingApp,output);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OPMRubinUIFigure = uifigure('Visible', 'off');
            app.OPMRubinUIFigure.Name = 'OPM Rubin';
            app.OPMRubinUIFigure.Icon = 'gu_device.png';
            app.OPMRubinLabel = uilabel(app.OPMRubinUIFigure);
            app.OPMRubinLabel.Text = 'OPM (Rubin)';
            app.FunctionPanel = uipanel(app.OPMRubinUIFigure);
            app.FunctionPanel.Enable = 'off';
            app.ChoosewavelengthDropDownLabel = uilabel(app.FunctionPanel);
            app.ChoosewavelengthDropDownLabel.Text = 'Choose wavelength';
            app.ChoosewavelengthDropDown = uidropdown(app.FunctionPanel);
            app.ChoosewavelengthDropDown.Items = {'1111', '2222', '3333'};
            app.ChoosewavelengthDropDown.Value = '1111';
            app.ReadpowerButton = uibutton(app.FunctionPanel, 'push');
            app.ReadpowerButton.ButtonPushedFcn = createCallbackFcn(app, @ReadpowerButtonPushed, true);
            app.ReadpowerButton.Text = 'Read power';
            app.PowerEditField = uieditfield(app.FunctionPanel, 'numeric');
            app.PowerWEditField = uieditfield(app.FunctionPanel, 'numeric');
            app.dBmLabel = uilabel(app.FunctionPanel);
            app.dBmLabel.Text = 'dBm';
            app.SaveButton = uibutton(app.FunctionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DropDown = uidropdown(app.FunctionPanel);
            app.DropDown.Items = {'nW', 'uW', 'mW', 'W'};
            app.DropDown.Value = 'mW';
            app.nmLabel = uilabel(app.FunctionPanel);
            app.nmLabel.Text = 'nm';
            app.ConnectPanel = uipanel(app.OPMRubinUIFigure);
            app.ConnectSwitch = uiswitch(app.ConnectPanel, 'slider');
            app.ConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.ConnectSwitch.Value = 'Disconnect';
            app.RefreshButton = uibutton(app.ConnectPanel, 'push');
            app.RefreshButton.Icon = 'gu_refresh.png';
            app.RefreshButton.Text = '';
            app.SerialportDropDownLabel = uilabel(app.ConnectPanel);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.ConnectPanel);
            app.SerialportDropDown.Items = {'COMXX'};
            app.SerialportDropDown.Value = 'COMXX';
            app.OPMnameEditFieldLabel = uilabel(app.ConnectPanel);
            app.OPMnameEditFieldLabel.Text = 'OPM name';
            app.OPMnameEditField = uieditfield(app.ConnectPanel, 'text');
            app.okButton = uibutton(app.OPMRubinUIFigure, 'push');
            app.okButton.Enable = 'off';
            app.okButton.Text = 'ok';
            app.COMportsEditFieldLabel = uilabel(app.OPMRubinUIFigure);
            app.COMportsEditFieldLabel.Text = 'COM-ports';
            app.OPMcomPortsEditField = uieditfield(app.OPMRubinUIFigure, 'text');
            app.OPMcomPortsEditField.Value = 'COM19 COM12';
            app.DisplaywavelengthButton = uibutton(app.OPMRubinUIFigure, 'push');
            app.DisplaywavelengthButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaywavelengthButtonPushed, true);
            app.DisplaywavelengthButton.Text = 'Display wavelength';
            app.EditField = uieditfield(app.OPMRubinUIFigure, 'text');
            % Show the figure after all components are created
            app.OPMRubinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_OPMrubin(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OPMRubinUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OPMRubinUIFigure)
        end
    end
end