classdef appAddDevice_SWITCHosaEdfa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        searchavailableserialportsButton  matlab.ui.control.Button
        COMportEditField              matlab.ui.control.EditField
        COMportEditFieldLabel         matlab.ui.control.Label
        SwitchpositionLabel           matlab.ui.control.Label
        ConfirmButton                 matlab.ui.control.Button
        ButtonGroup                   matlab.ui.container.ButtonGroup
        OSAButton                     matlab.ui.control.RadioButton
        EDFAButton                    matlab.ui.control.RadioButton
        SwitchOSAEDFA                 matlab.ui.control.Switch
        Panel                         matlab.ui.container.Panel
        Tip1Image                     matlab.ui.control.Image
        Switch_2                      matlab.ui.control.RockerSwitch
        SendButton                    matlab.ui.control.Button
        SubmitcustomrequestEditField  matlab.ui.control.EditField
        Label_3                       matlab.ui.control.Label
        HeaderLabel                   matlab.ui.control.Label
        RubinPanel                    matlab.ui.container.Panel
        RefreshButton                 matlab.ui.control.Button
        SerialportDropDown            matlab.ui.control.DropDown
        SerialportDropDownLabel       matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
    end

    
    properties (Access = public)
        switchOsaEdfa % Description
        callingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            %app.callingApp = MainApp;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Connect"
                app.switchOsaEdfa = [];
                app.switchOsaEdfa = SwitchOsaEdfa(app);
                output = 'SWITCH connected';
            elseif value == "Disconnect"
                if ~isequal(class(app.switchOsaEdfa),'double')
                    app.switchOsaEdfa = deleteVirtualObject(app.switchOsaEdfa);
                    output = 'SWITCH disconnected';
                end
            end
            printOutput(app.callingApp,output);
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            requestCommand = app.SubmitcustomrequestEditField.Value;
            sendCommand(app.switchOsaEdfa,requestCommand);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if app.ButtonGroup.SelectedObject == app.EDFAButton
                app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);
                app.SwitchOSAEDFA.Value = "EDFA";
            elseif app.ButtonGroup.SelectedObject == app.OSAButton
                app.switchOsaEdfa = switchSignalOSA(app.switchOsaEdfa);
                app.SwitchOSAEDFA.Value = "OSA";
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonButtonPushed(app, event)
            app.switchOsaEdfa = requestSwitchState(app.switchOsaEdfa);
            app.SwitchOSAEDFA.Value = app.switchOsaEdfa.switchState;
        end

        % Callback function
        function testButtonPushed(app, event)
            COMport = app.COMportEditField.Value;
            save('appSWITCH.mat','COMport')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RubinPanel = uipanel(app.UIFigure);
            app.Switch = uiswitch(app.RubinPanel, 'slider');
            app.Switch.Items = {'Disconnect', 'Connect'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Disconnect';
            app.SerialportDropDownLabel = uilabel(app.RubinPanel);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.RubinPanel);
            app.SerialportDropDown.Items = {'COMXX'};
            app.SerialportDropDown.Value = 'COMXX';
            app.RefreshButton = uibutton(app.RubinPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonButtonPushed, true);
            app.RefreshButton.Icon = 'gu_refresh.png';
            app.RefreshButton.Text = '';
            app.HeaderLabel = uilabel(app.UIFigure);
            app.HeaderLabel.Text = 'Switch (OSA/EDFA)';
            app.Panel = uipanel(app.UIFigure);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'Submit custom request';
            app.SubmitcustomrequestEditField = uieditfield(app.Panel, 'text');
            app.SubmitcustomrequestEditField.Tooltip = {'How to form a request:'; '1. Switch number (001,002 or 003)'; '2. Semicolon (;)'; '3. Switch state (0 or 1)'; '4. Semicolon (;)'; 'For example, 001;1; turn first switch on, you can see that first diode turn on.'};
            app.SubmitcustomrequestEditField.Value = '003;1;';
            app.SendButton = uibutton(app.Panel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'ok';
            app.Switch_2 = uiswitch(app.Panel, 'rocker');
            app.Switch_2.Items = {'OSA', 'EDFA'};
            app.Switch_2.Value = 'OSA';
            app.Tip1Image = uiimage(app.Panel);
            app.Tip1Image.Tooltip = {'Switch shows real state. If you change it not in program you would NOT see changes here.'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.SwitchOSAEDFA = uiswitch(app.UIFigure, 'slider');
            app.SwitchOSAEDFA.Items = {'OSA', 'EDFA'};
            app.SwitchOSAEDFA.Enable = 'off';
            app.SwitchOSAEDFA.Value = 'EDFA';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.EDFAButton = uiradiobutton(app.ButtonGroup);
            app.EDFAButton.Text = 'EDFA';
            app.EDFAButton.Value = true;
            app.OSAButton = uiradiobutton(app.ButtonGroup);
            app.OSAButton.Text = 'OSA';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Icon = 'icon_confirm.png';
            app.ConfirmButton.Tooltip = {'confirm'};
            app.ConfirmButton.Text = '';
            app.SwitchpositionLabel = uilabel(app.UIFigure);
            app.SwitchpositionLabel.Text = 'Switch position';
            app.COMportEditFieldLabel = uilabel(app.UIFigure);
            app.COMportEditFieldLabel.Text = 'COM-port';
            app.COMportEditField = uieditfield(app.UIFigure, 'text');
            app.COMportEditField.Value = 'COM3';
            app.searchavailableserialportsButton = uibutton(app.UIFigure, 'push');
            app.searchavailableserialportsButton.Tooltip = {'refresh'};
            app.searchavailableserialportsButton.Text = 'search available serial ports';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_SWITCHosaEdfa(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end