classdef appCalibrateEDFA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        saveResultButton              matlab.ui.control.Button
        savePoutButton                matlab.ui.control.Button
        savePinButton                 matlab.ui.control.Button
        Panel_5                       matlab.ui.container.Panel
        savetableCheckBox_3           matlab.ui.control.CheckBox
        writetoEEPROMCheckBox         matlab.ui.control.CheckBox
        writeCheckBox                 matlab.ui.control.CheckBox
        Label_12                      matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        getoffsetLabel                matlab.ui.control.Label
        getoffsetButton               matlab.ui.control.Button
        Label_8                       matlab.ui.control.Label
        readADCP_inforoffsetButton    matlab.ui.control.Button
        DWDMLabel                     matlab.ui.control.Label
        Panel_4                       matlab.ui.container.Panel
        readcoeffsfromfpgaButton      matlab.ui.control.Button
        writeinitcoeffstofpgaButton   matlab.ui.control.Button
        Label_9                       matlab.ui.control.Label
        Panel_3                       matlab.ui.container.Panel
        testcoeffButton_2             matlab.ui.control.Button
        getcoeffButton_2              matlab.ui.control.Button
        calibrateP_outButton          matlab.ui.control.Button
        savetableCheckBox_2           matlab.ui.control.CheckBox
        writeCheckBox_3               matlab.ui.control.CheckBox
        writetoEEPROMCheckBox_3       matlab.ui.control.CheckBox
        Label_11                      matlab.ui.control.Label
        coeffoutmkWADCEditField       matlab.ui.control.NumericEditField
        coeffoutmkWADCEditFieldLabel  matlab.ui.control.Label
        lossoutdBEditField            matlab.ui.control.NumericEditField
        lossoutdBEditFieldLabel       matlab.ui.control.Label
        VDLabel_2                     matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        testcoeffButton               matlab.ui.control.Button
        getcoeffButton                matlab.ui.control.Button
        calibrateP_inButton           matlab.ui.control.Button
        savetableCheckBox             matlab.ui.control.CheckBox
        Label_10                      matlab.ui.control.Label
        writeCheckBox_2               matlab.ui.control.CheckBox
        writetoEEPROMCheckBox_2       matlab.ui.control.CheckBox
        coeffinmkWADCEditField        matlab.ui.control.NumericEditField
        coeffinmkWADCEditFieldLabel   matlab.ui.control.Label
        lossindBEditField             matlab.ui.control.NumericEditField
        lossindBEditFieldLabel        matlab.ui.control.Label
        VDLabel                       matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        DropDown                      matlab.ui.control.DropDown
        Label_13                      matlab.ui.control.Label
        UserTextEditField             matlab.ui.control.EditField
        Label_3                       matlab.ui.control.Label
        LEditField                    matlab.ui.control.NumericEditField
        LEditFieldLabel               matlab.ui.control.Label
        deltaPrubinEditField          matlab.ui.control.NumericEditField
        deltaPrubinEditFieldLabel     matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        schemeImage                   matlab.ui.control.Image
        com1COMN2COMMCOMNCOMMLabel    matlab.ui.control.Label
        ApplyButton                   matlab.ui.control.Button
        downloadTableButton           matlab.ui.control.Button
        LineDropDown                  matlab.ui.control.DropDown
        Label                         matlab.ui.control.Label
        tableTextArea                 matlab.ui.control.TextArea
        Label_2                       matlab.ui.control.Label
        HeaderLabel                   matlab.ui.control.Label
    end


    properties (Access = public)
        fpga % Description
        osa
        opmIn
        opmOut
        switchOsaEdfa
        dicon
        sensor
    end
    properties
        callingApp
        tableAutoCalibPath
        %         tableAutoCalib
        rowNumberPin
        rowNumberPout
        opticLineNumber
        folder
        %tableVDforOFFSET
        tableInfo
        tableResultSample
        tablePin
        tablePout
        PIN
        POUT
        nameVDout_1
        nameVDout_2
        nameVDin_1
        nameVDin_2
        N
        mode
    end

    methods (Access = private)
        function app = readTableInfo(app)
            app.tableInfo = readtable(app.tableAutoCalibPath,...
                'Sheet','Info','ReadRowNames',true);
        end
        function app = readTableResultSample(app)
            app.tableResultSample = readtable(app.tableAutoCalibPath,...
                'Sheet','ResultSample','ReadRowNames',true);
            disp(app.tableResultSample)
        end
        function saveTableResultSample(app)
            userText = app.UserTextEditField.Value;
            tableName = createFilename([userText 'Result_'], now);
            writetable(app.tableResultSample,[app.folder tableName '.xlsx'],'AutoFitWidth',false,"WriteRowNames",true);
        end
        function app = readTablePin(app)
            tableArray = readtable(app.tableAutoCalibPath,'Sheet','Pin');
            app.rowNumberPin = height(tableArray);

            app.tablePin = tableArray;
        end
        function saveTablePin(app)
            userText = app.UserTextEditField.Value;
            tableName = createFilename([userText 'VDin_'], now);
            writetable(app.tablePin,[app.folder tableName '.xlsx'],'AutoFitWidth',false);
        end
        function app = readTablePout(app)
            app.tablePout = readtable(app.tableAutoCalibPath,'Sheet','Pout');
            app.rowNumberPout = height(app.tablePout);
        end
        function saveTablePout(app)
            userText = app.UserTextEditField.Value;
            %Pinput = num2strForPrint();
            s = [userText 'VDout_']; %Pinput ];
            tableName = createFilename(s, now);
            writetable(app.tablePout,[app.folder tableName '.xlsx'],'AutoFitWidth',false);
        end
        function nameVD_1 = getNameVD1(app,P)
            rowName = [app.LineDropDown.Value '_1'];
            nameVD_1 = app.tableInfo{rowName,P};
        end
        function nameVD_2 = getNameVD2(app,P)
            rowName = [app.LineDropDown.Value '_2'];
            nameVD_2 = app.tableInfo{rowName,P};
        end
        function app = getMeanForOffset(app,VD)
            arrayForMean = zeros(1,app.N);
            
            for i = 1:app.N
                disp(VD)
                app.fpga = readDataFromFPGA(app.fpga,VD);
                disp(app.fpga.lastRead)
                arrayForMean(i) = str2double(app.fpga.lastRead);
            end
            
            offset = round(-1*mean(arrayForMean));
            app.tableResultSample{VD,'OFFSET_'} = offset;
            disp('offset')
            disp(offset)
            app.fpga = writeDataToFPGA(app.fpga,['OFFSET_' VD], num2str(offset));
            app.fpga = readDataFromFPGA(app.fpga,['OFFSET_' VD]);
            app.fpga = writeDataToFPGAeeprom(app.fpga,['OFFSET_' VD],num2str(offset));
            disp(app.fpga.lastRead)
            pause(0.5)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;

            app.fpga = MainApp.calledAppFPGA.fpga;
            %app.osa = MainApp.calledAppOSA.osa;

            app.opmIn = MainApp.calledAppOPMrubin.opmArr.opticalPowerMeters(1);
            app.opmOut = MainApp.calledAppOPMrubin.opmArr.opticalPowerMeters(2);
            app.switchOsaEdfa = MainApp.calledAppSWITCH.switchOsaEdfa;
            app.dicon = MainApp.calledAppATTENdicon.attenuatorDicon;
            %app.sensor = MainApp.calledAppSENSOR.sensor;
            
            
        end

        % Button pushed function: downloadTableButton
        function downloadTableButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select table for autoCalibration','autoCalibration.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableAutoCalibPath = output;
            app.tableTextArea.Value = output;
        end

        % Button pushed function: readADCP_inforoffsetButton
        function readADCP_inforoffsetButtonPushed(app, event)


            app.fpga = turnOnOffDiodes(app.fpga,'off');
            disableWarningLaser(app.callingApp);

            app.opmIn = readPowerdBm(app.opmIn);
            if app.opmIn.lastReadPower > -50
                warning('мощность с рубина > -50 дБм');
            end

            nameVD_1 = getNameVD1(app,'P_in');
            varNames = app.tablePin.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA1',varNames);
            varNames(1,index) = nameVD_1;
            nameVD_1 = nameVD_1{1};
            app.tablePin.Properties.VariableNames = varNames;
            app.fpga = readDataFromFPGA(app.fpga,nameVD_1);
            app.tablePin{1,nameVD_1} = -1*str2double(app.fpga.lastRead);
            app.tableResultSample{nameVD_1,'OFFSET_'} = str2double(app.fpga.lastRead);
            str = ['OFFSET_' nameVD_1 ' = ' app.fpga.lastRead];
            addStrOutput(app.callingApp,str);

            app.fpga = readDataFromFPGA(app.fpga,'PIN1_FILT_CAL');
            app.tablePin{1,'PIN1_FILT_CAL'} = str2double(app.fpga.lastRead);
            app.fpga = readDataFromFPGA(app.fpga,'PIN2_FILT_CAL');
            app.tablePin{1,'PIN2_FILT_CAL'} = str2double(app.fpga.lastRead);

            nameVD_2 = getNameVD2(app,'P_in');
            varNames = app.tablePin.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA2',varNames);
            varNames(1,index) = nameVD_2;
            nameVD_2 = nameVD_2{1};
            %disp(varNames)
            app.tablePin.Properties.VariableNames = varNames;
            app.fpga = readDataFromFPGA(app.fpga,nameVD_2);
            app.tablePin{1,nameVD_2} = -1*str2double(app.fpga.lastRead);
            app.tableResultSample{nameVD_2,'OFFSET_'} = str2double(app.fpga.lastRead);
            str = ['OFFSET_' nameVD_2 ' = ' app.fpga.lastRead];
            addStrOutput(app.callingApp,str);

            app.calibrateP_inButton.Enable = 'on';
            
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.tableAutoCalibPath = app.tableTextArea.Value{1};

            app = readTableInfo(app);
            app = readTableResultSample(app);
            app = readTablePin(app);
            app = readTablePout(app);

            app.opticLineNumber = app.LineDropDown.Value;
            app.PIN = ['PIN' app.opticLineNumber(2) '_FILT_CAL'];
            %disp(app.PIN)
            app.POUT = ['POUT' app.opticLineNumber(2) '_FILT_CAL'];

            app.mode = app.DropDown.Value;
            
            app.Panel_2.Enable = 'on';
            app.Panel_3.Enable = 'on';
            app.Panel_4.Enable = 'on';

            app.switchOsaEdfa = switchSignalEDFA(app.switchOsaEdfa);

            app.nameVDout_1 = getNameVD1(app,'P_out');
            app.nameVDout_2 = getNameVD2(app,'P_out');
            varNames = app.tablePout.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA1',varNames);
            varNames(1,index) = app.nameVDout_1;
            app.tablePout.Properties.VariableNames = varNames;
            varNames = app.tablePout.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA2',varNames);
            varNames(1,index) = app.nameVDout_2;
            app.tablePout.Properties.VariableNames = varNames;
            app.nameVDout_1 = app.nameVDout_1{1};
            app.nameVDout_2 = app.nameVDout_2{1};

            app.nameVDin_1 = getNameVD1(app,'P_in');
            app.nameVDin_1 = app.nameVDin_1{1};
            app.nameVDin_2 = getNameVD2(app,'P_in');
            app.nameVDin_2 = app.nameVDin_2{1};
            varNames = app.tablePin.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA1',varNames);
            varNames(1,index) = {app.nameVDin_1};
            app.tablePin.Properties.VariableNames = varNames;
            varNames = app.tablePin.Properties.VariableNames;
            [logic, index] = ismember('ADCForNameFPGA2',varNames);
            varNames(1,index) = {app.nameVDin_2};
            app.tablePin.Properties.VariableNames = varNames;
            
            
        end

        % Button pushed function: calibrateP_inButton
        function calibrateP_inButtonPushed(app, event)
            app.fpga = turnOnOffDiodes(app.fpga,'off');
            disableWarningLaser(app.callingApp);

            for i = 1:app.rowNumberPin
                if i ~= 1 && app.tablePin{i,'InputPowerThDBm'} == app.tablePin{i-1,'InputPowerThDBm'}
                    n = 1;
                else
                    n = 3;
                end
                for j = 1:n
                    app.opmIn = readPowerdBm(app.opmIn);
                    powerReal = app.opmIn.lastReadPower;
                    powerTheor = app.tablePin{i,'InputPowerThDBm'};
                    currentAttenuation = app.dicon.attenuation;
                    attenuation = powerReal - powerTheor + currentAttenuation;
                    app.tablePin{i,'AttenuationForInputPowerDBm'} = attenuation;
                    app.dicon = setAttenuation(app.dicon,attenuation);
                    pause(1)
                end
                addStrOutput(app.callingApp,['Выставлена аттенюация ' num2strForPrint(attenuation) ' дБм']);

                pause(app.tablePin{i,'PauseAfterAttenuationS'});

                app.opmIn = readPowerdBm(app.opmIn);
                powerReal = app.opmIn.lastReadPower;

                app.tablePin{i,'InputPowerRealDBm'} = powerReal;
                newRow = {'Входная мощность (OPM), дБм',powerReal};
                appendMainTable(app.callingApp,newRow);

                powerRealmW = dBm2mkW(powerReal)/1000;
                app.tablePin{i,'InputPowerRealMW'} = powerRealmW;
                newRow = {'Входная мощность (OPM), мВт',powerRealmW};
                appendMainTable(app.callingApp,newRow);

                app.fpga = readDataFromFPGA(app.fpga,app.nameVDin_1);
                app.tablePin{i,app.nameVDin_1} = str2double(app.fpga.lastRead);
                newRow = {app.nameVDin_1,app.fpga.lastRead};
                appendMainTable(app.callingApp,newRow);

                app.fpga = readDataFromFPGA(app.fpga,app.nameVDin_2);
                app.tablePin{i,app.nameVDin_2} = str2double(app.fpga.lastRead);
                newRow = {app.nameVDin_2,app.fpga.lastRead};
                appendMainTable(app.callingApp,newRow);

                app.fpga = writeDataToFPGA(app.fpga,'SEL_PH_Pin','0');
                pause(0.5)
                app.fpga = readDataFromFPGA(app.fpga,app.PIN);
                app.tablePin{i,'PIN_1'} = str2double(app.fpga.lastRead);

                app.fpga = writeDataToFPGA(app.fpga,'SEL_PH_Pin','3');
                pause(0.5)
                app.fpga = readDataFromFPGA(app.fpga,app.PIN);
                app.tablePin{i,'PIN_2'} = str2double(app.fpga.lastRead);
            end
            addStrOutput(app.callingApp,'Входные ФП проверены');

            if app.savetableCheckBox.Value
                saveTablePin(app);
                addStrOutput(app.callingApp,'Таблица TablePin сохранена');
            end
        end

        % Button pushed function: saveResultButton
        function saveResultButtonPushed(app, event)
            saveTableResultSample(app);
            addStrOutput(app.callingApp,'Таблица TableResultSample сохранена');
        end

        % Button pushed function: savePinButton
        function savePinButtonPushed(app, event)
            saveTablePin(app);
            addStrOutput(app.callingApp,'Таблица TablePin сохранена');
        end

        % Button pushed function: savePoutButton
        function savePoutButtonPushed(app, event)
            saveTablePout(app);
            addStrOutput(app.callingApp,'Таблица TablePout сохранена');
        end

        % Button pushed function: calibrateP_outButton
        function calibrateP_outButtonPushed(app, event)
            for i = 1:app.rowNumberPout
                if i ~= 1 && app.tablePout{i,'InputPowerThDBm'} == app.tablePout{i-1,'InputPowerThDBm'}
                    n = 1;
                else
                    n = 3;
                end
                for j = 1:n
                    app.opmIn = readPowerdBm(app.opmIn);
                    powerReal = app.opmIn.lastReadPower;
                    powerTheor = app.tablePout{i,'InputPowerThDBm'};
                    currentAttenuation = app.dicon.attenuation;
                    attenuation = powerReal - powerTheor + currentAttenuation;
                    app.tablePout{i,'AttenuationForInputPowerDBm'} = attenuation;
                    app.dicon = setAttenuation(app.dicon,attenuation);
                    pause(1)
                end
                addStrOutput(app.callingApp,['Выставлена аттенюация ' num2strForPrint(attenuation) ' дБм']);
                pause(app.tablePout{i,'PauseAfterAttenuationS'});

                app.opmIn = readPowerdBm(app.opmIn);
                powerReal = app.opmIn.lastReadPower;

                app.tablePout{i,'InputPowerRealDBm'} = powerReal;
                newRow = {'Входная мощность (OPM), дБм',powerReal};
                appendMainTable(app.callingApp,newRow);

                powerRealmW = dBm2mkW(powerReal)/1000;
                app.tablePout{i,'InputPowerRealMW'} = powerRealmW;
                newRow = {'Входная мощность (OPM), мВт',powerRealmW};
                appendMainTable(app.callingApp,newRow);

                enableWarningLaser(app.callingApp);
                app.fpga = turnOnOffDiodes(app.fpga,'on');
                %powerValue = app.tablePout{i,'OutputPowerTh'};

                %%%% ЧАСТЬ РАБОТЫ С ТАБЛИЦЕЙ %%%%
                varNames = app.tablePout.Properties.VariableNames;
                name1 = varNames{1,6};
                name2 = varNames{1,7};

                app.fpga = writeDataToFPGA(app.fpga,'MODE','0');

                wvalue1 = num2str(app.tablePout{i,name1});
                app.fpga = writeDataToFPGA(app.fpga,name1,wvalue1);
                addStrOutput(app.callingApp,['Записано ' name1 ' = ' wvalue1]);

                wvalue2 = num2str(app.tablePout{i,name2});
                app.fpga = writeDataToFPGA(app.fpga,name2,wvalue2);
                addStrOutput(app.callingApp,['Записано ' name2 ' = ' wvalue2]);

                pauseSec = app.tablePout{i,'PauseAfterSetOutputPowerS'};
                addStrOutput(app.callingApp,['Ожидание ' num2strForPrint(pauseSec) ' с']);
                pause(pauseSec);
                %сделать аналогично тесту
                %setOutputPowerEDFA(app.fpga,app.opticLineNumber,powerValue);

                pause(app.tablePout{i,'PauseAfterSetOutputPowerS'});

                app.opmOut = readPowerdBm(app.opmOut);
                powerReal = app.opmOut.lastReadPower;
                powerReal = powerReal - app.deltaPrubinEditField.Value +...
                    app.LEditField.Value;
                app.tablePout{i,'OutputPowerRealDBm'} = powerReal;
                newRow = {'Выходная мощность (OPM), дБм',powerReal};
                appendMainTable(app.callingApp,newRow);

                powerRealmW = dBm2mkW(powerReal)/1000;
                app.tablePout{i,'OutputPowerRealMW'} = powerRealmW;
                newRow = {'Выходная мощность (OPM), мВт',powerRealmW};
                appendMainTable(app.callingApp,newRow);

                app.fpga = readDataFromFPGA(app.fpga,app.nameVDout_1);
                app.tablePout{i,app.nameVDout_1} = str2double(app.fpga.lastRead);
                newRow = {app.nameVDout_1,app.fpga.lastRead};
                appendMainTable(app.callingApp,newRow);

                app.fpga = readDataFromFPGA(app.fpga,app.nameVDout_2);
                app.tablePout{i,app.nameVDout_2} = str2double(app.fpga.lastRead);
                newRow = {app.nameVDout_2,app.fpga.lastRead};
                appendMainTable(app.callingApp,newRow);

                nameVDoffset_1 = ['OFFSET_' app.nameVDout_1];
                app.fpga = readDataFromFPGA(app.fpga,nameVDoffset_1);
                oldOffset_1 = app.fpga.lastRead;

                nameVDoffset_2 = ['OFFSET_' app.nameVDout_2];
                app.fpga = readDataFromFPGA(app.fpga,nameVDoffset_2);
                oldOffset_2 = app.fpga.lastRead;

                app.fpga = writeDataToFPGA(app.fpga,nameVDoffset_2,'-100000');
                pause(0.5);
                app.fpga = readDataFromFPGA(app.fpga,app.POUT);
                app.tablePout{i,'POUT_1'} = str2double(app.fpga.lastRead);
                app.fpga = writeDataToFPGA(app.fpga,nameVDoffset_2,oldOffset_2);

                app.fpga = writeDataToFPGA(app.fpga,nameVDoffset_1,'-100000');
                pause(0.5);
                app.fpga = readDataFromFPGA(app.fpga,app.POUT);
                app.tablePout{i,'POUT_2'} = str2double(app.fpga.lastRead);
                app.fpga = writeDataToFPGA(app.fpga,nameVDoffset_1,oldOffset_1);
            end

            disableWarningLaser(app.callingApp);
            app.fpga = turnOnOffDiodes(app.fpga,'off');
            wvalue1 = '0';
            app.fpga = writeDataToFPGA(app.fpga,name1,wvalue1);
            addStrOutput(app.callingApp,['Записано ' name1 ' = ' wvalue1]);
            wvalue2 = '0';
            app.fpga = writeDataToFPGA(app.fpga,name2,wvalue2);
            addStrOutput(app.callingApp,['Записано ' name2 ' = ' wvalue2]);
            
            addStrOutput(app.callingApp,'Выходные ФП проверены');

            if app.savetableCheckBox_2.Value
saveTablePout(app);
            addStrOutput(app.callingApp,'Таблица TablePout сохранена');
            end
        end

        % Button pushed function: getcoeffButton
        function getcoeffButtonPushed(app, event)
            tableVD = app.tablePin;
            lossdB = app.lossindBEditField.Value;
            k_mkWadc = app.coeffinmkWADCEditField.Value;

            coeff = addToCalibVDFUN(app.folder,'in','get',tableVD,lossdB,k_mkWadc,app.nameVDin_1);
            app.tableResultSample{app.nameVDin_1,'COEFF_'} = coeff;
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_1'];
%                 disp(coeffName)
%                 disp(coeff)
                app.fpga = writeDataToFPGA(app.fpga,coeffName,num2str(coeff));
                app.fpga = writeDataToFPGAeeprom(app.fpga,coeffName,num2str(coeff));
            end
            
            coeff = addToCalibVDFUN(app.folder,'in','get',tableVD,lossdB,k_mkWadc,app.nameVDin_2);
            app.tableResultSample{app.nameVDin_2,'COEFF_'} = coeff;
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_2'];
                app.fpga = writeDataToFPGA(app.fpga,coeffName,num2str(coeff));
                app.fpga = writeDataToFPGAeeprom(app.fpga,coeffName,num2str(coeff));
            end
            
            addStrOutput(app.callingApp,'Входные ФП обработаны');
        end

        % Button pushed function: getoffsetButton
        function getoffsetButtonPushed(app, event)
            app.N = app.EditField.Value;%количество снятий для вычисления среднего
            
            app = getMeanForOffset(app,app.nameVDin_1);
            app = getMeanForOffset(app,app.nameVDin_2);
            app = getMeanForOffset(app,app.nameVDout_1);
            app = getMeanForOffset(app,app.nameVDout_2);

            if app.savetableCheckBox_3.Value
                saveTableResultSample(app);
            addStrOutput(app.callingApp,'Таблица TableResultSample сохранена');
            end

            addStrOutput(app.callingApp,'Оффсеты записаны');
        end

        % Button pushed function: getcoeffButton_2
        function getcoeffButton_2Pushed(app, event)
            tableVD = app.tablePout;
            lossdB = app.lossoutdBEditField.Value;
            k_mkWadc = app.coeffoutmkWADCEditField.Value;
            
            coeff = addToCalibVDFUN(app.folder,'out','get',tableVD,lossdB,k_mkWadc,app.nameVDout_1);
            app.tableResultSample{app.nameVDout_1,'COEFF_'} = coeff;
            %disp(coeff)
            %disp(num2str(coeff))
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_1'];
                app.fpga = writeDataToFPGA(app.fpga,coeffName,num2str(coeff));
                app.fpga = writeDataToFPGAeeprom(app.fpga,coeffName,num2str(coeff));
            end
            
            coeff = addToCalibVDFUN(app.folder,'out','get',tableVD,lossdB,k_mkWadc,app.nameVDout_2);
            app.tableResultSample{app.nameVDout_2,'COEFF_'} = coeff;
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_2'];
                app.fpga = writeDataToFPGA(app.fpga,coeffName,num2str(coeff));
                app.fpga = writeDataToFPGAeeprom(app.fpga,coeffName,num2str(coeff));
            end
            addStrOutput(app.callingApp,'Выходные ФП обработаны');
        end

        % Button pushed function: writeinitcoeffstofpgaButton
        function writeinitcoeffstofpgaButtonPushed(app, event)
            app.fpga = writeDataToFPGA(app.fpga,'COEFF_Pin1_1','65535');
            app.fpga = writeDataToFPGA(app.fpga,'COEFF_Pin1_2','65535');
            app.fpga = writeDataToFPGA(app.fpga,'COEFF_Pin2_1','65535');
            app.fpga = writeDataToFPGA(app.fpga,'COEFF_Pin2_2','65535');
            app.fpga = writeDataToFPGA(app.fpga,'COEFF_Pout1_1','65535');
            app.fpga = writeDataToFPGA(app.fpga,'COEFF_Pout1_2','65535');
            app.fpga = writeDataToFPGA(app.fpga,'COEFF_Pout2_1','65535');
            app.fpga = writeDataToFPGA(app.fpga,'COEFF_Pout2_2','65535');
            addStrOutput(app.callingApp,'Нулевые коэффициенты записаны');
        end

        % Value changed function: savetableCheckBox_2
        function savetableCheckBox_2ValueChanged(app, event)
            value = app.savetableCheckBox_2.Value;
            
        end

        % Button pushed function: testcoeffButton
        function testcoeffButtonPushed(app, event)
            tableVD = app.tablePin;
            lossdB = app.lossindBEditField.Value;
            k_mkWadc = app.coeffinmkWADCEditField.Value;

            coeff = addToCalibVDFUN(app.folder,'in','test',tableVD,lossdB,k_mkWadc,app.nameVDin_1);
            app.tableResultSample{app.nameVDin_1,'COEFF_'} = coeff;
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_1'];
%                 disp(coeffName)
%                 disp(coeff)
                app.fpga = writeDataToFPGA(app.fpga,coeffName,num2str(coeff));
                app.fpga = writeDataToFPGAeeprom(app.fpga,coeffName,num2str(coeff));
            end
            
            coeff = addToCalibVDFUN(app.folder,'in','test',tableVD,lossdB,k_mkWadc,app.nameVDin_2);
            app.tableResultSample{app.nameVDin_2,'COEFF_'} = coeff;
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pin' app.opticLineNumber(end) '_2'];
                app.fpga = writeDataToFPGA(app.fpga,coeffName,num2str(coeff));
                app.fpga = writeDataToFPGAeeprom(app.fpga,coeffName,num2str(coeff));
            end
            
            addStrOutput(app.callingApp,'Входные ФП обработаны');
        end

        % Button pushed function: testcoeffButton_2
        function testcoeffButton_2Pushed(app, event)
            tableVD = app.tablePout;
            lossdB = app.lossoutdBEditField.Value;
            k_mkWadc = app.coeffoutmkWADCEditField.Value;
            
            coeff = addToCalibVDFUN(app.folder,'out','test',tableVD,lossdB,k_mkWadc,app.nameVDout_1);
            app.tableResultSample{app.nameVDout_1,'COEFF_'} = coeff;
            %disp(coeff)
            %disp(num2str(coeff))
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_1'];
                app.fpga = writeDataToFPGA(app.fpga,coeffName,num2str(coeff));
                app.fpga = writeDataToFPGAeeprom(app.fpga,coeffName,num2str(coeff));
            end
            
            coeff = addToCalibVDFUN(app.folder,'out','test',tableVD,lossdB,k_mkWadc,app.nameVDout_2);
            app.tableResultSample{app.nameVDout_2,'COEFF_'} = coeff;
            if app.writetoEEPROMCheckBox_2.Value
                coeffName = ['COEFF_Pout' app.opticLineNumber(end) '_2'];
                app.fpga = writeDataToFPGA(app.fpga,coeffName,num2str(coeff));
                app.fpga = writeDataToFPGAeeprom(app.fpga,coeffName,num2str(coeff));
            end
            addStrOutput(app.callingApp,'Выходные ФП обработаны');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HeaderLabel = uilabel(app.UIFigure);
            app.HeaderLabel.Text = 'Auto-calibration EDFA';
            app.Panel = uipanel(app.UIFigure);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '3. Добавьте таблицу для авто-калибровки';
            app.tableTextArea = uitextarea(app.Panel);
            app.tableTextArea.Value = {'autoCalibration.xlsx'};
            app.Label = uilabel(app.Panel);
            app.Label.Text = '4. Выберите линию';
            app.LineDropDown = uidropdown(app.Panel);
            app.LineDropDown.Items = {'L1', 'L2'};
            app.LineDropDown.Value = 'L1';
            app.downloadTableButton = uibutton(app.Panel, 'push');
            app.downloadTableButton.ButtonPushedFcn = createCallbackFcn(app, @downloadTableButtonPushed, true);
            app.downloadTableButton.Icon = 'icons8-download-64.png';
            app.downloadTableButton.Text = '';
            app.ApplyButton = uibutton(app.Panel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.com1COMN2COMMCOMNCOMMLabel = uilabel(app.Panel);
            app.com1COMN2COMMCOMNCOMMLabel.Text = '2. Убедитесь, что com-порты рубинов указаны в правильном порядке (если рубин1-COMN,рубин2-COMM,тогда COMN COMM). Иначе перезапустите.';
            app.schemeImage = uiimage(app.Panel);
            app.schemeImage.ImageSource = 'Inkedcalib_LI.jpg';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '1. Соберите схему. Введите значения ниже';
            app.deltaPrubinEditFieldLabel = uilabel(app.Panel);
            app.deltaPrubinEditFieldLabel.Text = 'delta P rubin';
            app.deltaPrubinEditField = uieditfield(app.Panel, 'numeric');
            app.deltaPrubinEditField.Tooltip = {'P_2 - P_1'};
            app.deltaPrubinEditField.Value = 0.13;
            app.LEditFieldLabel = uilabel(app.Panel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.Panel, 'numeric');
            app.LEditField.Tooltip = {'L > 0'};
            app.LEditField.Value = 0.41;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '5. Введите текст для названий таблиц';
            app.UserTextEditField = uieditfield(app.Panel, 'text');
            app.UserTextEditField.Value = 'myFirstCalibration';
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Enable = 'off';
            app.Label_13.Text = '6. Выберите режим';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'0', '2'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = '0';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Enable = 'off';
            app.VDLabel = uilabel(app.Panel_2);
            app.VDLabel.Text = 'Калибровка входных VD';
            app.lossindBEditFieldLabel = uilabel(app.Panel_2);
            app.lossindBEditFieldLabel.Text = 'loss(in), dB';
            app.lossindBEditField = uieditfield(app.Panel_2, 'numeric');
            app.lossindBEditField.Value = 19.26;
            app.coeffinmkWADCEditFieldLabel = uilabel(app.Panel_2);
            app.coeffinmkWADCEditFieldLabel.Text = 'coeff(in), mkW/ADC';
            app.coeffinmkWADCEditField = uieditfield(app.Panel_2, 'numeric');
            app.coeffinmkWADCEditField.Value = 0.00024;
            app.writetoEEPROMCheckBox_2 = uicheckbox(app.Panel_2);
            app.writetoEEPROMCheckBox_2.Text = 'write to EEPROM';
            app.writeCheckBox_2 = uicheckbox(app.Panel_2);
            app.writeCheckBox_2.Text = 'write';
            app.Label_10 = uilabel(app.Panel_2);
            app.Label_10.Text = 'Включите источник';
            app.savetableCheckBox = uicheckbox(app.Panel_2);
            app.savetableCheckBox.Text = 'save table';
            app.savetableCheckBox.Value = true;
            app.calibrateP_inButton = uibutton(app.Panel_2, 'push');
            app.calibrateP_inButton.ButtonPushedFcn = createCallbackFcn(app, @calibrateP_inButtonPushed, true);
            app.calibrateP_inButton.Text = 'calibrate P_in';
            app.getcoeffButton = uibutton(app.Panel_2, 'push');
            app.getcoeffButton.ButtonPushedFcn = createCallbackFcn(app, @getcoeffButtonPushed, true);
            app.getcoeffButton.Text = 'get coeff';
            app.testcoeffButton = uibutton(app.Panel_2, 'push');
            app.testcoeffButton.ButtonPushedFcn = createCallbackFcn(app, @testcoeffButtonPushed, true);
            app.testcoeffButton.Tooltip = {'Читает коэффициенты с ПЛИС и выводит в '};
            app.testcoeffButton.Text = 'test coeff';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Enable = 'off';
            app.VDLabel_2 = uilabel(app.Panel_3);
            app.VDLabel_2.Text = 'Калибровка выходных VD';
            app.lossoutdBEditFieldLabel = uilabel(app.Panel_3);
            app.lossoutdBEditFieldLabel.Text = 'loss(out), dB';
            app.lossoutdBEditField = uieditfield(app.Panel_3, 'numeric');
            app.lossoutdBEditField.Value = 17.66;
            app.coeffoutmkWADCEditFieldLabel = uilabel(app.Panel_3);
            app.coeffoutmkWADCEditFieldLabel.Text = 'coeff(out), mkW/ADC';
            app.coeffoutmkWADCEditField = uieditfield(app.Panel_3, 'numeric');
            app.coeffoutmkWADCEditField.Value = 0.00457;
            app.Label_11 = uilabel(app.Panel_3);
            app.Label_11.Text = 'Включите источник';
            app.writetoEEPROMCheckBox_3 = uicheckbox(app.Panel_3);
            app.writetoEEPROMCheckBox_3.Text = 'write to EEPROM';
            app.writeCheckBox_3 = uicheckbox(app.Panel_3);
            app.writeCheckBox_3.Text = 'write';
            app.savetableCheckBox_2 = uicheckbox(app.Panel_3);
            app.savetableCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @savetableCheckBox_2ValueChanged, true);
            app.savetableCheckBox_2.Text = 'save table';
            app.savetableCheckBox_2.Value = true;
            app.calibrateP_outButton = uibutton(app.Panel_3, 'push');
            app.calibrateP_outButton.ButtonPushedFcn = createCallbackFcn(app, @calibrateP_outButtonPushed, true);
            app.calibrateP_outButton.Text = 'calibrate P_out';
            app.getcoeffButton_2 = uibutton(app.Panel_3, 'push');
            app.getcoeffButton_2.ButtonPushedFcn = createCallbackFcn(app, @getcoeffButton_2Pushed, true);
            app.getcoeffButton_2.Text = 'get coeff';
            app.testcoeffButton_2 = uibutton(app.Panel_3, 'push');
            app.testcoeffButton_2.ButtonPushedFcn = createCallbackFcn(app, @testcoeffButton_2Pushed, true);
            app.testcoeffButton_2.Tooltip = {'Читает коэффициенты с ПЛИС и выводит в '};
            app.testcoeffButton_2.Text = 'test coeff';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Enable = 'off';
            app.Label_9 = uilabel(app.Panel_4);
            app.Label_9.Text = 'Перед началом калибровки убедитесь, что записаны нулевые коэффициенты';
            app.writeinitcoeffstofpgaButton = uibutton(app.Panel_4, 'push');
            app.writeinitcoeffstofpgaButton.ButtonPushedFcn = createCallbackFcn(app, @writeinitcoeffstofpgaButtonPushed, true);
            app.writeinitcoeffstofpgaButton.Text = 'write init coeffs to fpga';
            app.readcoeffsfromfpgaButton = uibutton(app.Panel_4, 'push');
            app.readcoeffsfromfpgaButton.Tooltip = {'Читает коэффициенты с ПЛИС и выводит в '};
            app.readcoeffsfromfpgaButton.Text = 'read coeffs from fpga';
            app.DWDMLabel = uilabel(app.UIFigure);
            app.DWDMLabel.Enable = 'off';
            app.DWDMLabel.Text = '1. Выключите источник (DWDM-сетку) и диоды';
            app.readADCP_inforoffsetButton = uibutton(app.UIFigure, 'push');
            app.readADCP_inforoffsetButton.ButtonPushedFcn = createCallbackFcn(app, @readADCP_inforoffsetButtonPushed, true);
            app.readADCP_inforoffsetButton.Enable = 'off';
            app.readADCP_inforoffsetButton.Text = 'read ADC P_in for offset';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'ВКЛЮЧИТЕ СЕТКУ';
            app.Panel_5 = uipanel(app.UIFigure);
            app.getoffsetButton = uibutton(app.Panel_5, 'push');
            app.getoffsetButton.ButtonPushedFcn = createCallbackFcn(app, @getoffsetButtonPushed, true);
            app.getoffsetButton.Text = 'get offset';
            app.getoffsetLabel = uilabel(app.Panel_5);
            app.getoffsetLabel.Text = {'Выключите источник. Чтобы опросить фотоприемники            раз'; 'и получить смещения, нажмите кнопку get offset'};
            app.EditField = uieditfield(app.Panel_5, 'numeric');
            app.EditField.Value = 40;
            app.Label_12 = uilabel(app.Panel_5);
            app.Label_12.Text = 'Определение смещений';
            app.writeCheckBox = uicheckbox(app.Panel_5);
            app.writeCheckBox.Text = 'write';
            app.writeCheckBox.Value = true;
            app.writetoEEPROMCheckBox = uicheckbox(app.Panel_5);
            app.writetoEEPROMCheckBox.Text = 'write to EEPROM';
            app.writetoEEPROMCheckBox.Value = true;
            app.savetableCheckBox_3 = uicheckbox(app.Panel_5);
            app.savetableCheckBox_3.Text = 'save table';
            app.savetableCheckBox_3.Value = true;
            app.savePinButton = uibutton(app.UIFigure, 'push');
            app.savePinButton.ButtonPushedFcn = createCallbackFcn(app, @savePinButtonPushed, true);
            app.savePinButton.Text = 'save Pin';
            app.savePoutButton = uibutton(app.UIFigure, 'push');
            app.savePoutButton.ButtonPushedFcn = createCallbackFcn(app, @savePoutButtonPushed, true);
            app.savePoutButton.Text = 'save Pout';
            app.saveResultButton = uibutton(app.UIFigure, 'push');
            app.saveResultButton.ButtonPushedFcn = createCallbackFcn(app, @saveResultButtonPushed, true);
            app.saveResultButton.Text = 'save Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appCalibrateEDFA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end