classdef appTestFPGA_Attenuator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TestattenuatorUIFigure       matlab.ui.Figure
        saveLabel                    matlab.ui.control.Label
        Label_22                     matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Label_21                     matlab.ui.control.Label
        PanelFunction_2              matlab.ui.container.Panel
        IfthetestpassedpresssavebuttonLabel  matlab.ui.control.Label
        AssembleopticalcircuitforreferencepowerwithoutattenuatorLabel  matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        dBmLabel_2                   matlab.ui.control.Label
        PinputEditField              matlab.ui.control.NumericEditField
        PinputEditFieldLabel         matlab.ui.control.Label
        ApplyButton_Pinput           matlab.ui.control.Button
        resetButton                  matlab.ui.control.Button
        PanelSettings                matlab.ui.container.Panel
        SelectsampletableforattenuatorTextArea  matlab.ui.control.TextArea
        Label_24                     matlab.ui.control.Label
        SelecttablewithallcomponentsTextArea  matlab.ui.control.TextArea
        Label_23                     matlab.ui.control.Label
        downloadButton_2             matlab.ui.control.Button
        downloadButton_1             matlab.ui.control.Button
        TestmodeDropDown             matlab.ui.control.DropDown
        TestmodeDropDownLabel        matlab.ui.control.Label
        ApplyButton                  matlab.ui.control.Button
        CommentEditField             matlab.ui.control.EditField
        CommentEditFieldLabel        matlab.ui.control.Label
        BoardnumberEditField         matlab.ui.control.EditField
        BoardnumberEditFieldLabel    matlab.ui.control.Label
        DesignationEditField         matlab.ui.control.EditField
        DesignationLabel             matlab.ui.control.Label
        SerialnumberEditField        matlab.ui.control.EditField
        SerialnumberLabel            matlab.ui.control.Label
        InspectornameEditField       matlab.ui.control.EditField
        InspectornameEditFieldLabel  matlab.ui.control.Label
        TestFPGAattenuatorLabel      matlab.ui.control.Label
        PanelFunction                matlab.ui.container.Panel
        Button_2                     matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        AssembleopticalcircuitLabel  matlab.ui.control.Label
        dBmLabel                     matlab.ui.control.Label
        DACvalueEditField            matlab.ui.control.NumericEditField
        DACvalueEditFieldLabel       matlab.ui.control.Label
        PoutputEditField             matlab.ui.control.NumericEditField
        PoutputEditFieldLabel        matlab.ui.control.Label
        ApplyButton_2                matlab.ui.control.Button
    end

    
    properties (Access = public)
        callingApp
        folder
        time
        tableComponent
        tableAttenuator
        fpga
        opm

        numberDAC
        i
        attenuator
    end

    methods (Access = public)
        
        function app = nextDACvalue(app)
            app.i = app.i + 1;
            dac2write = num2str(app.attenuator.dac(app.i));
            app.fpga = writeDataToFPGA(app.fpga,app.attenuator.fpgaName,dac2write);
            app.DACvalueEditField.Value = str2double(app.fpga.lastRead);
            addStrOutput(app.callingApp,['Значение на ЦАП '...
                num2strForPrint(app.attenuator.dac(app.i))]);
            addStrOutput(app.callingApp,['Напряжение на аттенюаторе '...
                num2strForPrint(app.attenuator.voltage(app.i)) ' В']);
            addStrOutput(app.callingApp,['Время ожидания '...
                num2strForPrint(app.attenuator.timeDelay(app.i)) ' с']);
            pause(app.attenuator.timeDelay(app.i));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
%             app.callingApp = MainApp;
%             app.folder = MainApp.folder;
%             
%             app.fpga = MainApp.calledAppFPGA.fpga;
%             if ~isempty(MainApp.calledAppOPMrubin)
%                 app.opm = MainApp.calledAppOPMrubin.opmArr.opticalPowerMeters(1);
%             end
%             if ~isempty(MainApp.calledAppOPMthorlabs)
%                 app.opm = MainApp.calledAppOPMthorlabs.opm;
%             end
%             
%             app.i = 0;
%             
%             app.tableComponent = 'tableComponent.xlsx';
%             app.tableAttenuator = 'tableAttenuator.xlsx';
        end

        % Button pushed function: downloadButton_1
        function downloadButton_1Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableComponent file','tableComponent.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.TestattenuatorUIFigure);
            app.tableComponent = output;
            app.SelecttablewithallcomponentsTextArea.Value = output;
        end

        % Button pushed function: downloadButton_2
        function downloadButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableAttenuator file','tableAttenuator.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.TestattenuatorUIFigure);
            app.tableAttenuator = output;
            app.SelectsampletableforattenuatorTextArea.Value = output;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
%             try
                app.time = now;
                app.attenuator = Attenuator(app);
                app.numberDAC = length(app.attenuator.dac);
                app.PanelFunction.Enable = 'on';
                app = nextDACvalue(app);
                if isequal(app.attenuator.testMode,'manual')
                    app.ApplyButton_Pinput.Text = 'apply';
                elseif isequal(app.attenuator.testMode,'auto(rubin)')
                    app.PoutputEditField.Editable ='off';
                    %app.PinputEditField.Editable ='off';
                    app.ApplyButton_Pinput.Text = 'read power';
                    app.ApplyButton_2.Text = 'read power';
                end
                app.PanelSettings.Enable = 'off';
%             catch ME
%                 fig = uifigure;
%                 uialert(fig,ME.message,'Failed');
%             end
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            if isequal(app.attenuator.testMode,'manual')
                Poutput = app.PoutputEditField.Value;
                app.attenuator = savePowerOutput(app.attenuator,Poutput);
                if app.i < app.numberDAC
                    app = nextDACvalue(app);
                elseif app.i == app.numberDAC
                    %app.ApplyButton_2.Enable = 'off';
                    %app.ApplyButton_2.Text = 'finished';
                    addStrOutput(app.callingApp,'Измерения по схеме 1 окончены');
                end
                app.PoutputEditField.Value = 0;
            elseif isequal(app.attenuator.testMode,'auto(rubin)')    
                app.ApplyButton_2.Enable = 'off';
                app.opm = readPowerdBm(app.opm);
                Poutput = app.opm.lastReadPower;
                app.PoutputEditField.Value = Poutput;
                
                app.attenuator = savePowerOutput(app.attenuator,Poutput);
                while app.i < app.numberDAC
                    app = nextDACvalue(app);
                    app.opm = readPowerdBm(app.opm);
                    Poutput = app.opm.lastReadPower;
                    app.PoutputEditField.Value = Poutput;
                    addStrOutput(app.callingApp,['P output = '...
                        num2strForPrint(Poutput) ' дБм']);
                    app.attenuator = savePowerOutput(app.attenuator,Poutput);
                end
                addStrOutput(app.callingApp,'Измерения по схеме 1 окончены');
                %app.ApplyButton_2.Text = 'finished';
            end
        end

        % Button pushed function: ApplyButton_Pinput
        function ApplyButton_PinputPushed(app, event)
%             if isequal(app.attenuator.testMode,'auto(rubin)')
%                 app.opm = readPowerdBm(app.opm);
%                 app.PinputEditField.Value = app.opm.lastReadPower;
%             end
            app.attenuator = savePowerInput(app.attenuator,app.PinputEditField.Value);
            addStrOutput(app.callingApp,['P input = '...
                num2strForPrint(app.PinputEditField.Value) ' дБм']);
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            %app.callingApp.
            %delete(app);
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Сохранение данных
            saveTables(app.attenuator);
            attenuatorName = createFilename('AT',app.attenuator.time);
            attenuator = app.attenuator;
            save([app.folder attenuatorName '.mat'],'attenuator');
            addStrOutput(app.callingApp,'Файлы сохранены');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            imageview(app.Image.ImageSource);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TestattenuatorUIFigure = uifigure('Visible', 'off');
            app.TestattenuatorUIFigure.Name = 'Test attenuator';
            app.TestattenuatorUIFigure.Icon = 'gu_action.png';
            app.PanelFunction = uipanel(app.TestattenuatorUIFigure);
            app.ApplyButton_2 = uibutton(app.PanelFunction, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.PoutputEditFieldLabel = uilabel(app.PanelFunction);
            app.PoutputEditFieldLabel.Text = 'P output';
            app.PoutputEditField = uieditfield(app.PanelFunction, 'numeric');
            app.DACvalueEditFieldLabel = uilabel(app.PanelFunction);
            app.DACvalueEditFieldLabel.Text = 'DAC value';
            app.DACvalueEditField = uieditfield(app.PanelFunction, 'numeric');
            app.DACvalueEditField.ValueDisplayFormat = '%.0f';
            app.DACvalueEditField.Editable = 'off';
            app.dBmLabel = uilabel(app.PanelFunction);
            app.dBmLabel.Text = 'dBm';
            app.AssembleopticalcircuitLabel = uilabel(app.PanelFunction);
            app.AssembleopticalcircuitLabel.Text = 'Assemble optical circuit with attenuator, fill in power values (manual mode) or press Apply (auto mode)';
            app.Image = uiimage(app.PanelFunction);
            app.Image.ImageSource = 'FPGA_attenuator.png';
            app.Button_2 = uibutton(app.PanelFunction, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'gu_zoomin.png';
            app.Button_2.Text = '';
            app.TestFPGAattenuatorLabel = uilabel(app.TestattenuatorUIFigure);
            app.TestFPGAattenuatorLabel.Text = 'Test FPGA attenuator';
            app.PanelSettings = uipanel(app.TestattenuatorUIFigure);
            app.InspectornameEditFieldLabel = uilabel(app.PanelSettings);
            app.InspectornameEditFieldLabel.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.PanelSettings, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.SerialnumberLabel = uilabel(app.PanelSettings);
            app.SerialnumberLabel.Text = 'Serial number';
            app.SerialnumberEditField = uieditfield(app.PanelSettings, 'text');
            app.SerialnumberEditField.Value = '15B6KMG01869';
            app.DesignationLabel = uilabel(app.PanelSettings);
            app.DesignationLabel.Text = 'Designation';
            app.DesignationEditField = uieditfield(app.PanelSettings, 'text');
            app.DesignationEditField.Value = 'A1';
            app.BoardnumberEditFieldLabel = uilabel(app.PanelSettings);
            app.BoardnumberEditFieldLabel.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.PanelSettings, 'text');
            app.BoardnumberEditField.Value = '01';
            app.CommentEditFieldLabel = uilabel(app.PanelSettings);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.PanelSettings, 'text');
            app.CommentEditField.Value = 'T = 22 C';
            app.ApplyButton = uibutton(app.PanelSettings, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TestmodeDropDownLabel = uilabel(app.PanelSettings);
            app.TestmodeDropDownLabel.Text = 'Test mode';
            app.TestmodeDropDown = uidropdown(app.PanelSettings);
            app.TestmodeDropDown.Items = {'manual', 'auto(rubin)', 'auto(thorlabs)'};
            app.TestmodeDropDown.Value = 'auto(rubin)';
            app.downloadButton_1 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_1.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_1Pushed, true);
            app.downloadButton_1.Icon = 'gu_microsoft-excel.png';
            app.downloadButton_1.Text = '';
            app.downloadButton_2 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_2.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_2Pushed, true);
            app.downloadButton_2.Icon = 'gu_microsoft-excel.png';
            app.downloadButton_2.Text = '';
            app.Label_23 = uilabel(app.PanelSettings);
            app.Label_23.Text = 'Select table with all components';
            app.SelecttablewithallcomponentsTextArea = uitextarea(app.PanelSettings);
            app.SelecttablewithallcomponentsTextArea.Value = {'tableComponent.xlsx'};
            app.Label_24 = uilabel(app.PanelSettings);
            app.Label_24.Text = 'Select sample table for attenuator';
            app.SelectsampletableforattenuatorTextArea = uitextarea(app.PanelSettings);
            app.SelectsampletableforattenuatorTextArea.Value = {'tableAttenuator.xlsx'};
            app.resetButton = uibutton(app.TestattenuatorUIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'reset';
            app.PanelFunction_2 = uipanel(app.TestattenuatorUIFigure);
            app.ApplyButton_Pinput = uibutton(app.PanelFunction_2, 'push');
            app.ApplyButton_Pinput.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_PinputPushed, true);
            app.ApplyButton_Pinput.Text = 'Apply/read rubin';
            app.PinputEditFieldLabel = uilabel(app.PanelFunction_2);
            app.PinputEditFieldLabel.Text = 'P input';
            app.PinputEditField = uieditfield(app.PanelFunction_2, 'numeric');
            app.dBmLabel_2 = uilabel(app.PanelFunction_2);
            app.dBmLabel_2.Text = 'dBm';
            app.Button = uibutton(app.PanelFunction_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'gu_save.png';
            app.Button.Text = '';
            app.AssembleopticalcircuitforreferencepowerwithoutattenuatorLabel = uilabel(app.PanelFunction_2);
            app.AssembleopticalcircuitforreferencepowerwithoutattenuatorLabel.Text = 'Assemble optical circuit for reference power (without attenuator), fill in power value (manual mode) or press Apply (auto mode)';
            app.IfthetestpassedpresssavebuttonLabel = uilabel(app.PanelFunction_2);
            app.IfthetestpassedpresssavebuttonLabel.Text = 'If the test passed, press save button';
            app.Label_21 = uilabel(app.TestattenuatorUIFigure);
            app.Label_21.Text = '2. Соберите схему, заполните поле (если режим ручной) и нажмите кнопку';
            app.Image2 = uiimage(app.TestattenuatorUIFigure);
            app.Image2.ImageSource = 'atten2.png';
            app.Label_22 = uilabel(app.TestattenuatorUIFigure);
            app.Label_22.Text = '3. Соберите схему и нажмите кнопку/впишите мощность';
            app.saveLabel = uilabel(app.TestattenuatorUIFigure);
            app.saveLabel.Text = '4. Сохраните данные проверок - нажмите save';
            % Show the figure after all components are created
            app.TestattenuatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_Attenuator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TestattenuatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TestattenuatorUIFigure)
        end
    end
end