classdef appTestFPGA_LaserDiode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PoutputEditField               matlab.ui.control.EditField
        PoutputEditFieldLabel          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        LDDN_OFFSET_HIGH_RANGEEditField  matlab.ui.control.EditField
        LDDN_OFFSET_HIGH_RANGEEditFieldLabel  matlab.ui.control.Label
        Tip1Image                      matlab.ui.control.Image
        checkButton                    matlab.ui.control.Button
        saveLabel                      matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        LoadDriverEEPROMLabel          matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        Label_5                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        PanelFunction                  matlab.ui.container.Panel
        Button_2                       matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        AssembleopticalcircuitLabel    matlab.ui.control.Label
        OutputpowerEditField           matlab.ui.control.NumericEditField
        OutputpowerEditFieldLabel      matlab.ui.control.Label
        savevalueButton                matlab.ui.control.Button
        Tip1Image_2                    matlab.ui.control.Image
        IfthetestpassedpresssavebuttonLabel  matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        LDDN_CURRENTEditField          matlab.ui.control.EditField
        LDDN_CURRENTEditFieldLabel     matlab.ui.control.Label
        writetofpgaButton              matlab.ui.control.Button
        resetButton                    matlab.ui.control.Button
        PanelSettings                  matlab.ui.container.Panel
        OperatingwlEditField           matlab.ui.control.EditField
        OperatingwlEditField_2Label    matlab.ui.control.Label
        CommentEditField               matlab.ui.control.EditField
        CommentEditField_2Label        matlab.ui.control.Label
        BoardnumberEditField           matlab.ui.control.EditField
        BoardnumberEditField_2Label    matlab.ui.control.Label
        DesignationEditField           matlab.ui.control.EditField
        DesignationEditField_2Label    matlab.ui.control.Label
        SerialnumberEditField          matlab.ui.control.EditField
        SerialnumberEditField_2Label   matlab.ui.control.Label
        InspectornameEditField         matlab.ui.control.EditField
        InspectornameEditField_2Label  matlab.ui.control.Label
        ApplyButton                    matlab.ui.control.Button
        SelecttablewithallcomponentsTextArea  matlab.ui.control.TextArea
        SelecttablewithallcomponentsTextAreaLabel  matlab.ui.control.Label
        SelectsampletableforlaserdiodeTextArea  matlab.ui.control.TextArea
        Label_2                        matlab.ui.control.Label
        downloadButton_2               matlab.ui.control.Button
        downloadButton_1               matlab.ui.control.Button
        TestFPGAlaserdiodeLabel        matlab.ui.control.Label
    end


    properties (Access = public)
        callingApp
        folder
        time

        fpga
        tableComponent
        tableLaserDiode

        LDnumber
        %laserDiode
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            app.fpga = MainApp.calledAppFPGA.fpga;

            app.tableComponent = 'tableComponent.xlsx';
            app.tableLaserDiode = 'tableLaserDiode.xlsx';
        end

        % Button pushed function: downloadButton_1
        function downloadButton_1Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableComponent file','tableComponent.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableComponent = output;
            app.SelecttablewithallcomponentsTextArea.Value = output;
        end

        % Button pushed function: downloadButton_2
        function downloadButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableLaserDiode file','tableLaserDiode.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableLaserDiode = output;
            app.SelectsampletableforlaserdiodeTextArea.Value = output;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            designation = app.DesignationEditField.Value;
            if isequal(designation,'PB1')
                app.LDnumber = '1';
            elseif isequal(designation,'PB2')
                app.LDnumber = '2';
            elseif isequal(designation,'PB3')
                app.LDnumber = '3';
            elseif isequal(designation,'PB4')
                app.LDnumber = '4';
            end
            app.PanelFunction.Enable = 'on';
        end

        % Button pushed function: writetofpgaButton
        function writetofpgaButtonPushed(app, event)
            number = str2double(app.LDnumber);
            wvalue = num2str(2^(number-1));
            app.fpga = writeDataToFPGA(app.fpga,'LDD_EN',wvalue);
            
            enableWarningLaser(app.callingApp);
            output = 'Диоды включены';
            addStrOutput(app.callingApp,output);

            fpgaName = ['LDD' app.LDnumber '_CURRENT'];
            wvalue = app.LDDN_CURRENTEditField.Value;
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);
            
            output = ['На диод N=' app.LDnumber ' подается ток ' wvalue];
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: checkButton
        function checkButtonPushed(app, event)
            fpgaName = ['LDD' app.LDnumber '_OFFSET_HIGH_RANGE'];
            app.fpga = readDataFromFPGA(app.fpga,fpgaName);
            offset = app.fpga.lastRead;
            app.LDDN_OFFSET_HIGH_RANGEEditField.Value = offset;
            output = ['Смещение диода N=' app.LDnumber ' равно ' offset];
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.time = now;
            laserDiode = LaserDiode(app);
            
            fpgaName = ['LDD' app.LDnumber '_CURRENT'];
            wvalue = '0';
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);
            
            app.fpga = writeDataToFPGA(app.fpga,'LDD_EN','0');
            output = 'Диоды выключены';
            addStrOutput(app.callingApp,output);
            disableWarningLaser(app.callingApp);
            
            saveTable(laserDiode);
            laserDiodeName = createFilename('LD',app.time);
            save([app.folder laserDiodeName '.mat'],'laserDiode');
            addStrOutput(app.callingApp,'Файлы сохранены');
            
            app.Button.Enable = 'off';
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            %app.callingApp.
            %delete(app);

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            disp(value)
%             if isequal(value,'on')
%                 app.fpga = writeDataToFPGA(app.fpga,'LDD_EN','F');
%                 output = 'Диоды включены';
%                 addStrOutput(app.callingApp,output);
%                 enableWarningLaser(app.callingApp);
%             else
%                 app.fpga = writeDataToFPGA(app.fpga,'LDD_EN','0');
%                 output = 'Диоды выключены';
%                 addStrOutput(app.callingApp,output);
%                 disableWarningLaser(app.callingApp);
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestFPGAlaserdiodeLabel = uilabel(app.UIFigure);
            app.TestFPGAlaserdiodeLabel.Text = 'Test FPGA laser diode';
            app.PanelSettings = uipanel(app.UIFigure);
            app.downloadButton_1 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_1.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_1Pushed, true);
            app.downloadButton_1.Icon = 'gu_microsoft-excel.png';
            app.downloadButton_1.Text = '';
            app.downloadButton_2 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_2.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_2Pushed, true);
            app.downloadButton_2.Icon = 'gu_microsoft-excel.png';
            app.downloadButton_2.Text = '';
            app.Label_2 = uilabel(app.PanelSettings);
            app.Label_2.Text = 'Select sample table for laser diode';
            app.SelectsampletableforlaserdiodeTextArea = uitextarea(app.PanelSettings);
            app.SelectsampletableforlaserdiodeTextArea.Value = {'tableLaserDiode.xlsx'};
            app.SelecttablewithallcomponentsTextAreaLabel = uilabel(app.PanelSettings);
            app.SelecttablewithallcomponentsTextAreaLabel.Text = 'Select table with all components';
            app.SelecttablewithallcomponentsTextArea = uitextarea(app.PanelSettings);
            app.SelecttablewithallcomponentsTextArea.Value = {'tableComponent.xlsx'};
            app.ApplyButton = uibutton(app.PanelSettings, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.InspectornameEditField_2Label = uilabel(app.PanelSettings);
            app.InspectornameEditField_2Label.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.PanelSettings, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.SerialnumberEditField_2Label = uilabel(app.PanelSettings);
            app.SerialnumberEditField_2Label.Text = 'Serial number';
            app.SerialnumberEditField = uieditfield(app.PanelSettings, 'text');
            app.DesignationEditField_2Label = uilabel(app.PanelSettings);
            app.DesignationEditField_2Label.Text = 'Designation';
            app.DesignationEditField = uieditfield(app.PanelSettings, 'text');
            app.DesignationEditField.Value = 'PB1';
            app.BoardnumberEditField_2Label = uilabel(app.PanelSettings);
            app.BoardnumberEditField_2Label.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.PanelSettings, 'text');
            app.BoardnumberEditField.Value = '01';
            app.CommentEditField_2Label = uilabel(app.PanelSettings);
            app.CommentEditField_2Label.Text = 'Comment';
            app.CommentEditField = uieditfield(app.PanelSettings, 'text');
            app.CommentEditField.Value = 'в корпусе';
            app.OperatingwlEditField_2Label = uilabel(app.PanelSettings);
            app.OperatingwlEditField_2Label.Text = 'Operating wl';
            app.OperatingwlEditField = uieditfield(app.PanelSettings, 'text');
            app.OperatingwlEditField.Value = '980';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'reset';
            app.PanelFunction = uipanel(app.UIFigure);
            app.writetofpgaButton = uibutton(app.PanelFunction, 'push');
            app.writetofpgaButton.ButtonPushedFcn = createCallbackFcn(app, @writetofpgaButtonPushed, true);
            app.writetofpgaButton.Text = 'write to fpga';
            app.LDDN_CURRENTEditFieldLabel = uilabel(app.PanelFunction);
            app.LDDN_CURRENTEditFieldLabel.Text = 'LDDN_CURRENT';
            app.LDDN_CURRENTEditField = uieditfield(app.PanelFunction, 'text');
            app.Button = uibutton(app.PanelFunction, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'gu_save.png';
            app.Button.Text = '';
            app.dBmLabel = uilabel(app.PanelFunction);
            app.dBmLabel.Text = 'dBm';
            app.IfthetestpassedpresssavebuttonLabel = uilabel(app.PanelFunction);
            app.IfthetestpassedpresssavebuttonLabel.Text = 'If the test passed, press save button';
            app.Tip1Image_2 = uiimage(app.PanelFunction);
            app.Tip1Image_2.Tooltip = {'We recommend to check values 1022,1023,1024.'};
            app.Tip1Image_2.ImageSource = 'gu_help.png';
            app.savevalueButton = uibutton(app.PanelFunction, 'push');
            app.savevalueButton.Text = 'save value';
            app.OutputpowerEditFieldLabel = uilabel(app.PanelFunction);
            app.OutputpowerEditFieldLabel.Text = 'Output power';
            app.OutputpowerEditField = uieditfield(app.PanelFunction, 'numeric');
            app.AssembleopticalcircuitLabel = uilabel(app.PanelFunction);
            app.AssembleopticalcircuitLabel.Text = 'Assemble optical circuit at picture, fill in fields';
            app.Image = uiimage(app.PanelFunction);
            app.Image.ImageSource = 'FPGA_LaserDiode.png';
            app.Button_2 = uibutton(app.PanelFunction, 'push');
            app.Button_2.Icon = 'gu_zoomin.png';
            app.Button_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1. Добавьте таблицу со всеми компонентами (дополняется строкой после проверки) и таблицу-шаблон для проверки лазерных диодов';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Лазерные диоды';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.LoadDriverEEPROMLabel = uilabel(app.UIFigure);
            app.LoadDriverEEPROMLabel.Text = '2. Если значение ниже равно нулю, найдите в таблице калибровки (Load Driver для этой платы) нужное значение и впишите его в EEPROM. Только после этого продолжайте проверку.';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3. Введите значение тока, которое хотите выставить на плате и нажмите кнопку.';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4. Измерьте мощность лазерного диода и впишите ниже';
            app.saveLabel = uilabel(app.UIFigure);
            app.saveLabel.Text = '5. Сохраните данные проверок - нажмите save';
            app.Panel = uipanel(app.UIFigure);
            app.checkButton = uibutton(app.Panel, 'push');
            app.checkButton.ButtonPushedFcn = createCallbackFcn(app, @checkButtonPushed, true);
            app.checkButton.Text = 'check';
            app.Tip1Image = uiimage(app.Panel);
            app.Tip1Image.Tooltip = {'If this value 0 you would NOT be allowed to continue. You can change it in Device FPGA window (don''t forget to find right value and write it in EEPROM).'};
            app.Tip1Image.ImageSource = 'gu_help.png';
            app.LDDN_OFFSET_HIGH_RANGEEditFieldLabel = uilabel(app.Panel);
            app.LDDN_OFFSET_HIGH_RANGEEditFieldLabel.Text = 'LDDN_OFFSET_HIGH_RANGE';
            app.LDDN_OFFSET_HIGH_RANGEEditField = uieditfield(app.Panel, 'text');
            app.LDDN_OFFSET_HIGH_RANGEEditField.Editable = 'off';
            app.PoutputEditFieldLabel = uilabel(app.UIFigure);
            app.PoutputEditFieldLabel.Text = 'P output';
            app.PoutputEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestFPGA_LaserDiode(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end