classdef appTestLoadDriver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        resetButton                  matlab.ui.control.Button
        PanelSettings                matlab.ui.container.Panel
        CommentEditField             matlab.ui.control.EditField
        CommentEditFieldLabel        matlab.ui.control.Label
        BoardnumberEditField         matlab.ui.control.EditField
        BoardnumberEditFieldLabel    matlab.ui.control.Label
        DesignationEditField         matlab.ui.control.EditField
        DesignationEditFieldLabel    matlab.ui.control.Label
        InspectornameEditField       matlab.ui.control.EditField
        InspectornameEditFieldLabel  matlab.ui.control.Label
        ApplyButton                  matlab.ui.control.Button
        downloadButton_2             matlab.ui.control.Button
        TextArea_2                   matlab.ui.control.TextArea
        Label_4                      matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        downloadButton_1             matlab.ui.control.Button
        TestloaddriverLabel          matlab.ui.control.Label
        PanelFunction                matlab.ui.container.Panel
        VLabel                       matlab.ui.control.Label
        runButton                    matlab.ui.control.Button
        nextButton                   matlab.ui.control.Button
        VoltageVEditField            matlab.ui.control.NumericEditField
        VoltageVEditFieldLabel       matlab.ui.control.Label
        LDDcurrentEditField          matlab.ui.control.EditField
        LDDcurrentEditFieldLabel     matlab.ui.control.Label
        saveButton                   matlab.ui.control.Button
        saveLabel                    matlab.ui.control.Label
        LDDN_CURRENTEditField        matlab.ui.control.EditField
        LDDN_CURRENTEditFieldLabel   matlab.ui.control.Label
        writetofpgaButton            matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        runnextLabel                 matlab.ui.control.Label
        Voltagecurrent1023Label      matlab.ui.control.Label
        Button_2                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        LDDN_OFFSET_EditField        matlab.ui.control.EditField
        LDDN_OFFSET_EditFieldLabel   matlab.ui.control.Label
        designationLabel             matlab.ui.control.Label
        Voltagecurrent1023EditField  matlab.ui.control.EditField
        Voltagecurrent1023EditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        callingApp
        folder
        time

        dac
        voltage

        fpga
        tableComponent
        tableLoadDriver

        LDnumber %string
        DACnumber %num
        %loadDriver
    end

    methods (Access = private)
        function dac = readTableForDAC(app)
            numRows = readtable(app.tableLoadDriver,'Sheet','Sample',...
                'ReadVariableNames',false,'ReadRowNames',true);
            dac = numRows{'Значение на ЦАП',:};
        end
        function app = writeDACvalue(app)
            number = str2double(app.LDnumber);
            valueNum = app.dac(app.DACnumber);
            app.fpga = setValueToLddCurrent(app.fpga,number,valueNum);
            app.LDDcurrentEditField.Value = app.fpga.lastRead;
            output = ['Номер измерения ',...
                num2str(app.DACnumber)];
            addStrOutput(app.callingApp,output);
            output = ['Значение ЦАП ',...
                num2str(app.dac(app.DACnumber))];
            addStrOutput(app.callingApp,output);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            app.fpga = MainApp.calledAppFPGA.fpga;

            app.tableComponent = 'tableComponent.xlsx';
            app.tableLoadDriver = 'tableLoadDriver.xlsx';
        end

        % Button pushed function: downloadButton_1
        function downloadButton_1Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableComponent file','tableComponent.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableComponent = output;
            app.TextArea.Value = output;
        end

        % Button pushed function: downloadButton_2
        function downloadButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableLoadDriver file','tableLoadDriver.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableLoadDriver = output;
            app.TextArea_2.Value = output;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.LDnumber = app.DesignationEditField.Value;
            app.dac = readTableForDAC(app);

            fpgaName = ['LDD' app.LDnumber '_OFFSET_HIGH_RANGE'];
            wvalue = '0';
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);
            app.LDDN_OFFSET_EditField.Value = app.fpga.lastRead;
            output = ['Значение ' wvalue ' записано в ' fpgaName];
            addStrOutput(app.callingApp,output);

            app.PanelSettings.Enable = 'off';
            app.PanelFunction.Enable = 'on';
        end

        % Button pushed function: writetofpgaButton
        function writetofpgaButtonPushed(app, event)
            number = str2double(app.LDnumber);
            wvalue = num2str(2^(number-1));
            app.fpga = writeDataToFPGA(app.fpga,'LDD_EN',wvalue);
            
            output = 'Диоды включены';
            addStrOutput(app.callingApp,output);
            enableWarningLaser(app.callingApp);

            fpgaName = ['LDD' app.LDnumber '_CURRENT'];
            wvalue = app.LDDN_CURRENTEditField.Value;
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);
            
            output = ['На диод N=' app.LDnumber ' подается ток ' wvalue];
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            app.DACnumber = 1;
            app = writeDACvalue(app);
            app.nextButton.Enable = 'on';
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            app.voltage(app.DACnumber) = app.VoltageVEditField.Value;
            output = ['Значение напряжения ',...
                num2strForPrint(app.voltage(app.DACnumber))];
            addStrOutput(app.callingApp,output);
            if app.DACnumber < length(app.dac)
                app.DACnumber = app.DACnumber + 1;
                app = writeDACvalue(app);
            elseif app.DACnumber == length(app.dac)
                app.nextButton.Enable = 'off';
                addStrOutput(app.callingApp,'Все измерения проведены, можно сохранять');
            end
        end

        % Value changed function: LDDN_OFFSET_EditField
        function LDDN_OFFSET_EditFieldValueChanged(app, event)
            wvalue = app.LDDN_OFFSET_EditField.Value;
            fpgaName = ['LDD' app.LDnumber '_OFFSET_HIGH_RANGE'];
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);
            app.LDDN_OFFSET_EditField.Value = app.fpga.lastRead;
            output = ['Значение ' wvalue ' записано в ' fpgaName];
            addStrOutput(app.callingApp,output);
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            %app.callingApp.
            %delete(app);

        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.time = now;
            loadDriver = LoadDriver(app);

            fpgaName = ['LDD' app.LDnumber '_CURRENT'];
            wvalue = '0';
            app.fpga = writeDataToFPGA(app.fpga,fpgaName,wvalue);

            app.fpga = writeDataToFPGA(app.fpga,'LDD_EN','0');

            output = 'Диоды выключены';
            addStrOutput(app.callingApp,output);
            disableWarningLaser(app.callingApp);
            
            saveTable(loadDriver);
            loadDriverName = createFilename('Ld',app.time);
            save([app.folder loadDriverName '.mat'],'loadDriver');
            addStrOutput(app.callingApp,'Файлы сохранены');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelFunction = uipanel(app.UIFigure);
            app.PanelFunction.Enable = 'off';
            app.Voltagecurrent1023EditFieldLabel = uilabel(app.PanelFunction);
            app.Voltagecurrent1023EditFieldLabel.Text = 'Voltage (current 1023)';
            app.Voltagecurrent1023EditField = uieditfield(app.PanelFunction, 'text');
            app.designationLabel = uilabel(app.PanelFunction);
            app.designationLabel.Text = '2. Установите драйвер нагрузки согласно рисунку. Подайте ток 1023 на указанный "диод" (designation), пользуясь полем и кнопкой ниже. Введите напряжение для этого значения тока';
            app.LDDN_OFFSET_EditFieldLabel = uilabel(app.PanelFunction);
            app.LDDN_OFFSET_EditFieldLabel.Text = 'LDDN_OFFSET_...';
            app.LDDN_OFFSET_EditField = uieditfield(app.PanelFunction, 'text');
            app.LDDN_OFFSET_EditField.ValueChangedFcn = createCallbackFcn(app, @LDDN_OFFSET_EditFieldValueChanged, true);
            app.Button = uibutton(app.PanelFunction, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.PanelFunction, 'push');
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '-';
            app.Voltagecurrent1023Label = uilabel(app.PanelFunction);
            app.Voltagecurrent1023Label.Text = '3. Подайте ток 1024. Изменяйте оффсет пока не получите значение напряжения чуть больше, чем Voltage (current 1023)';
            app.runnextLabel = uilabel(app.PanelFunction);
            app.runnextLabel.Text = '4. Когда значение будет подобрано, нажмите run. Ток на диоде будет меняться (из таблицы) и его значение вы можете видеть ниже, вводите напряжение для каждого значения и нажимайте  next';
            app.Image = uiimage(app.PanelFunction);
            app.writetofpgaButton = uibutton(app.PanelFunction, 'push');
            app.writetofpgaButton.ButtonPushedFcn = createCallbackFcn(app, @writetofpgaButtonPushed, true);
            app.writetofpgaButton.Text = 'write to fpga';
            app.LDDN_CURRENTEditFieldLabel = uilabel(app.PanelFunction);
            app.LDDN_CURRENTEditFieldLabel.Text = 'LDDN_CURRENT';
            app.LDDN_CURRENTEditField = uieditfield(app.PanelFunction, 'text');
            app.LDDN_CURRENTEditField.Value = '1023';
            app.saveLabel = uilabel(app.PanelFunction);
            app.saveLabel.Text = '5. Сохраните данные проверок - нажмите save';
            app.saveButton = uibutton(app.PanelFunction, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.LDDcurrentEditFieldLabel = uilabel(app.PanelFunction);
            app.LDDcurrentEditFieldLabel.Text = 'LDD current';
            app.LDDcurrentEditField = uieditfield(app.PanelFunction, 'text');
            app.LDDcurrentEditField.Editable = 'off';
            app.VoltageVEditFieldLabel = uilabel(app.PanelFunction);
            app.VoltageVEditFieldLabel.Text = 'Voltage, V';
            app.VoltageVEditField = uieditfield(app.PanelFunction, 'numeric');
            app.nextButton = uibutton(app.PanelFunction, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.runButton = uibutton(app.PanelFunction, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.VLabel = uilabel(app.PanelFunction);
            app.VLabel.Text = 'V';
            app.TestloaddriverLabel = uilabel(app.UIFigure);
            app.TestloaddriverLabel.Text = 'Test load driver';
            app.PanelSettings = uipanel(app.UIFigure);
            app.downloadButton_1 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_1.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_1Pushed, true);
            app.downloadButton_1.Icon = 'icons8-microsoft-excel-512.png';
            app.downloadButton_1.Text = '';
            app.Label = uilabel(app.PanelSettings);
            app.Label.Text = '1. Добавьте таблицу со всеми компонентами (дополняется строкой после проверки) и таблицу-шаблон для проверки драйвера (имитатора нагрузки)';
            app.TextAreaLabel = uilabel(app.PanelSettings);
            app.TextAreaLabel.Text = 'Выбранная таблица со всеми компонентами:';
            app.TextArea = uitextarea(app.PanelSettings);
            app.TextArea.Value = {'tableComponent.xlsx'};
            app.Label_4 = uilabel(app.PanelSettings);
            app.Label_4.Text = 'Выбранная таблица-шаблон для драйвера (имитатора нагрузки):';
            app.TextArea_2 = uitextarea(app.PanelSettings);
            app.TextArea_2.Value = {'tableLoadDriver.xlsx'};
            app.downloadButton_2 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_2.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_2Pushed, true);
            app.downloadButton_2.Icon = 'icons8-microsoft-excel-512.png';
            app.downloadButton_2.Text = '';
            app.ApplyButton = uibutton(app.PanelSettings, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.InspectornameEditFieldLabel = uilabel(app.PanelSettings);
            app.InspectornameEditFieldLabel.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.PanelSettings, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.DesignationEditFieldLabel = uilabel(app.PanelSettings);
            app.DesignationEditFieldLabel.Text = 'Designation';
            app.DesignationEditField = uieditfield(app.PanelSettings, 'text');
            app.DesignationEditField.Value = '1';
            app.BoardnumberEditFieldLabel = uilabel(app.PanelSettings);
            app.BoardnumberEditFieldLabel.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.PanelSettings, 'text');
            app.BoardnumberEditField.Value = '01';
            app.CommentEditFieldLabel = uilabel(app.PanelSettings);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.PanelSettings, 'text');
            app.CommentEditField.Value = 'до заливки';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestLoadDriver(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end