classdef appTestPhotodiode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        resetButton                  matlab.ui.control.Button
        PanelSettings                matlab.ui.container.Panel
        TestmodeDropDown             matlab.ui.control.DropDown
        TestmodeDropDownLabel        matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        Label_4                      matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        downloadButton_2             matlab.ui.control.Button
        downloadButton_1             matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        CommentEditField             matlab.ui.control.EditField
        CommentEditFieldLabel        matlab.ui.control.Label
        BoardnumberEditField         matlab.ui.control.EditField
        BoardnumberEditFieldLabel    matlab.ui.control.Label
        InspectornameEditField       matlab.ui.control.EditField
        InspectornameEditFieldLabel  matlab.ui.control.Label
        TestphotodiodesLabel         matlab.ui.control.Label
        PanelFunction                matlab.ui.container.Panel
        dBmLabel_2                   matlab.ui.control.Label
        dBmLabel                     matlab.ui.control.Label
        Power1550EditField           matlab.ui.control.NumericEditField
        Label_7                      matlab.ui.control.Label
        Power980EditField            matlab.ui.control.NumericEditField
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        NumberformeanEditField       matlab.ui.control.EditField
        Label_3                      matlab.ui.control.Label
        saveButton                   matlab.ui.control.Button
        SourcesEditField             matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        runLabel                     matlab.ui.control.Label
        runButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        callingApp
        folder
        time
        tableComponent
        tablePhotodiode
        opm980
        opm1550
        fpga

        tableArray
        %numberMeasurement % номер испытания
        powerFPGA
        meanPowerFPGA
        power980
        meanPower980
        power1550
        meanPower1550
        meanPowerOPM
        
        photodiodeArr
        tableArrayStr
    end
    
    methods (Access = public)
        
        function app = getOpmAndFpgaPower(app)
            
            for i = 1:str2double(app.NumberformeanEditField.Value)
                if isequal(app.TestmodeDropDown.Value,'auto(rubin)')
                    app.opm980 = readPowerdBm(app.opm980);
                    app.power980(i) = app.opm980.lastReadPower;
                    app.Power980EditField.Value = app.power980(i);
                    output = ['Мощность на измерителе 980 нм = ' num2str(app.power980(i))];
                    addStrOutput(app.callingApp,output);
                    app.opm1550 = readPowerdBm(app.opm1550);
                    app.power1550(i) = app.opm1550.lastReadPower;
                    app.Power1550EditField.Value = app.power1550(i);
                    output = ['Мощность на измерителе 1550 нм = ' num2str(app.power1550(i))];
                    addStrOutput(app.callingApp,output);
                elseif isequal(app.TestmodeDropDown.Value,'manual')
                    app.power980(i) = app.Power980EditField.Value;
                    app.power1550(i) = app.Power1550EditField.Value;
                end
                for numberVD = 1:height(app.tableArray)
                    fpgaName = app.tableArray{numberVD,'FPGAAddress'};
                    fpgaName = fpgaName{1};
                    app.fpga = readDataFromFPGA(app.fpga,fpgaName);
                    output = [fpgaName ' = ' app.fpga.lastRead];
                    addStrOutput(app.callingApp,output);
                    app.powerFPGA(numberVD,i) = str2double(app.fpga.lastRead);
                end
                
            end
            
        end
        
        function app = calcMeanPower(app)
            app.meanPower980 = mean(app.power980);
            app.meanPower1550 = mean(app.power1550);
            for numberVD = 1:height(app.tableArray)
                app.meanPowerFPGA = mean(app.powerFPGA(numberVD,:));
                if 980 == app.tableArray{numberVD,'OperatingWavelength'}
                    fullPow = app.meanPower980;
                else
                    fullPow = app.meanPower1550;
                end
                loss = app.tableArray{numberVD,'Loss'};
                app.meanPowerOPM = fullPow - loss;
                
                photodiode = app.photodiodeArr{numberVD};
                photodiode = addMeasurement(photodiode,app);
                app.photodiodeArr{numberVD} = photodiode;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            app.folder = MainApp.folder;
            
            app.fpga = MainApp.calledAppFPGA.fpga;
            if ~isempty(MainApp.calledAppOPMrubin)
                opm = MainApp.calledAppOPMrubin.opmArr.opticalPowerMeters(1);
                if opm.wavelength == 980
                    app.opm980 = opm;
                    app.opm1550 = MainApp.calledAppOPMrubin.opmArr.opticalPowerMeters(2);
                else
                    app.opm980 = MainApp.calledAppOPMrubin.opmArr.opticalPowerMeters(2);
                    app.opm1550 = opm;
                end
            end
            
            app.tableComponent = 'tableComponent.xlsx';
            app.tablePhotodiode = 'tablePhotodiode.xlsx';
        end

        % Button pushed function: downloadButton_1
        function downloadButton_1Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tableComponent file','tableComponent.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tableComponent = output;
            app.TextArea.Value = output;
        end

        % Button pushed function: downloadButton_2
        function downloadButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx','Select tablePhotodiode file','tablePhotodiode.xlsx');
            if isequal(file,0)
                output = "не выбрана";
            else
                output = [path file];
            end
            figure(app.UIFigure);
            app.tablePhotodiode = output;
            app.TextArea_2.Value = output;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            %try
                app.tableArray = readtable(app.tablePhotodiode,'Sheet','Array');
                for numberVD = 1:height(app.tableArray)
                    app.time = now;
                    app.tableArrayStr = app.tableArray(numberVD,:);
                    photodiode = Photodiode(app);
                    app.photodiodeArr{numberVD} = photodiode;
                end
                app.PanelFunction.Enable = 'on';
                app.PanelSettings.Enable = 'off';
                
                if isequal(app.TestmodeDropDown.Value,'auto(rubin)')
                    app.Power980EditField.Editable = 'off';
                    app.Power1550EditField.Editable = 'off';
                end
%             catch ME
%                 fig = uifigure;
%                 uialert(fig,ME.message,'Failed');
%             end
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            app = getOpmAndFpgaPower(app);
            app = calcMeanPower(app);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            for numberVD = 1:height(app.tableArray)
                photodiode = app.photodiodeArr{numberVD};
                saveTable(photodiode);
                s1 = photodiode.boardNumber;
                s2 = photodiode.designation;
                s3 = photodiode.serialNumber;
                strForName = ['B' s1 '_' s2 '_' s3];
                photodiodeName = createFilename(strForName,app.time);
                save([app.folder photodiodeName '.mat'],'photodiode');
                addStrOutput(app.callingApp,['Файлы сохранены для диода ' s2]);
                app.saveButton.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelFunction = uipanel(app.UIFigure);
            app.PanelFunction.Enable = 'off';
            app.runButton = uibutton(app.PanelFunction, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.runLabel = uilabel(app.PanelFunction);
            app.runLabel.Text = 'Соберите схему, внесите инф-ю об источниках и нажмите кнопку run.';
            app.Image = uiimage(app.PanelFunction);
            app.EditFieldLabel = uilabel(app.PanelFunction);
            app.EditFieldLabel.Text = 'Состояние источников';
            app.SourcesEditField = uieditfield(app.PanelFunction, 'text');
            app.SourcesEditField.Value = 'вклвыклmaxmin';
            app.saveButton = uibutton(app.PanelFunction, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.Label_3 = uilabel(app.PanelFunction);
            app.Label_3.Text = 'Кол-во измерений для вычисления среднего';
            app.NumberformeanEditField = uieditfield(app.PanelFunction, 'text');
            app.NumberformeanEditField.Value = '5';
            app.Label_5 = uilabel(app.PanelFunction);
            app.Label_5.Text = 'Сделайте хотя бы 2 измерения';
            app.Label_6 = uilabel(app.PanelFunction);
            app.Label_6.Text = 'Мощность на 980 нм';
            app.Power980EditField = uieditfield(app.PanelFunction, 'numeric');
            app.Label_7 = uilabel(app.PanelFunction);
            app.Label_7.Text = 'Мощность на 1550 нм';
            app.Power1550EditField = uieditfield(app.PanelFunction, 'numeric');
            app.dBmLabel = uilabel(app.PanelFunction);
            app.dBmLabel.Text = 'dBm';
            app.dBmLabel_2 = uilabel(app.PanelFunction);
            app.dBmLabel_2.Text = 'dBm';
            app.TestphotodiodesLabel = uilabel(app.UIFigure);
            app.TestphotodiodesLabel.Text = 'Test photodiodes';
            app.PanelSettings = uipanel(app.UIFigure);
            app.InspectornameEditFieldLabel = uilabel(app.PanelSettings);
            app.InspectornameEditFieldLabel.Text = 'Inspector name';
            app.InspectornameEditField = uieditfield(app.PanelSettings, 'text');
            app.InspectornameEditField.Value = 'Фамилия И.О.';
            app.BoardnumberEditFieldLabel = uilabel(app.PanelSettings);
            app.BoardnumberEditFieldLabel.Text = 'Board number';
            app.BoardnumberEditField = uieditfield(app.PanelSettings, 'text');
            app.BoardnumberEditField.Value = '01';
            app.CommentEditFieldLabel = uilabel(app.PanelSettings);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.PanelSettings, 'text');
            app.CommentEditField.Value = 'до заливки T 24 C';
            app.ApplyButton = uibutton(app.PanelSettings, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.downloadButton_1 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_1.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_1Pushed, true);
            app.downloadButton_1.Icon = 'icons8-microsoft-excel-512.png';
            app.downloadButton_1.Text = '';
            app.downloadButton_2 = uibutton(app.PanelSettings, 'push');
            app.downloadButton_2.ButtonPushedFcn = createCallbackFcn(app, @downloadButton_2Pushed, true);
            app.downloadButton_2.Icon = 'icons8-microsoft-excel-512.png';
            app.downloadButton_2.Text = '';
            app.Label = uilabel(app.PanelSettings);
            app.Label.Text = 'Добавьте таблицу со всеми компонентами (дополняется строкой после проверки) и таблицу-шаблон для проверки фотодиодов (проверьте потери и серийные номера!)';
            app.TextAreaLabel = uilabel(app.PanelSettings);
            app.TextAreaLabel.Text = 'Выбранная таблица со всеми компонентами:';
            app.TextArea = uitextarea(app.PanelSettings);
            app.TextArea.Value = {'tableComponent.xlsx'};
            app.Label_4 = uilabel(app.PanelSettings);
            app.Label_4.Text = 'Выбранная таблица-шаблон для фотоприемников:';
            app.TextArea_2 = uitextarea(app.PanelSettings);
            app.TextArea_2.Value = {'tablePhotodiode.xlsx'};
            app.TestmodeDropDownLabel = uilabel(app.PanelSettings);
            app.TestmodeDropDownLabel.Text = 'Test mode';
            app.TestmodeDropDown = uidropdown(app.PanelSettings);
            app.TestmodeDropDown.Items = {'manual', 'auto(rubin)', '-auto(thorlabs)'};
            app.TestmodeDropDown.Value = 'manual';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTestPhotodiode(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end